classdef cell_annotation < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        FileMenu                matlab.ui.container.Menu
        OpenMenu                matlab.ui.container.Menu
        ImportAnnotationsMenu   matlab.ui.container.Menu
        SaveMenu                matlab.ui.container.Menu
        Export2PImageMenu       matlab.ui.container.Menu
        ExportStackImageMenu    matlab.ui.container.Menu
        SettingsMenu            matlab.ui.container.Menu
        ReformatFileNamesMenu   matlab.ui.container.Menu
        RenameChannelsMenu      matlab.ui.container.Menu
        ChannelSlider           matlab.ui.control.Slider
        ChannelSliderLabel      matlab.ui.control.Label
        Channel1Button          matlab.ui.control.StateButton
        Channel2Button          matlab.ui.control.StateButton
        Channel3Button          matlab.ui.control.StateButton
        Channel4Button          matlab.ui.control.StateButton
        Channel5Button          matlab.ui.control.StateButton
        Channel6Button          matlab.ui.control.StateButton
        Channel7Button          matlab.ui.control.StateButton
        Channel8Button          matlab.ui.control.StateButton
        Channel9Button          matlab.ui.control.StateButton
        Channel10Button         matlab.ui.control.StateButton
        AdjustContrastButton    matlab.ui.control.Button
        AdjustContrastButton_2  matlab.ui.control.Button
        UIAxes_2P               matlab.ui.control.UIAxes
        UIAxes_Stack            matlab.ui.control.UIAxes
    end


    properties (Access = public)

        buttonnames                     % channel button names
        reformatapp                     % secondary app to reformat file names for loading
        prefix2P = 'Depth5'             % prefix for 2P image file name
        separator2P = '-'               % separator for 2P image file name
        suffix2P                        % suffix for 2P image file name
        prefixStack = 'Image_01'        % prefix for stack file name
        separatorStack = '-'            % separatot for stack file name
        suffixStack                     % prefix for stack file name
        renameapp                       % secondary app to rename channel names
        tilename                        % image name
        stackname                       % stack of images name
        image_annotate                  % image data to be annotated
        image_stack                     % stack of stained image data
        roi1                            % 2P ellipse ROI data for channel 1
        hroi1                           % current 2P ellipse ROI for channel 1
        roi1s                           % stack ellipse ROI data for channel 1
        hroi1s                          % current stack ellipse ROI for channel 1
        index1                          % index for ellipse ROI for channel 1
        roi2                            % 2P ellipse ROI data for channel 2
        hroi2                           % current 2P ellipse ROI for channel 2
        roi2s                           % stack ellipse ROI data for channel 2
        hroi2s                          % current stack ellipse ROI for channel 2
        index2                          % index for 2P ellipse ROI for channel 2
        roi3                            % 2P ellipse ROI data for channel 3
        hroi3                           % current 2P ellipse ROI for channel 3
        roi3s                           % stack ellipse ROI data for channel 3
        hroi3s                          % current stack ellipse ROI for channel 3
        index3                          % index for 2P ellipse ROI for channel 3
        roi4                            % 2P ellipse ROI data for channel 4
        hroi4                           % current 2P ellipse ROI for channel 4
        roi4s                           % stack ellipse ROI data for channel 4
        hroi4s                          % current stack ellipse ROI for channel 4
        index4                          % index for 2P ellipse ROI for channel 4
        roi5                            % 2P ellipse ROI data for channel 5
        hroi5                           % current 2P ellipse ROI for channel 5
        roi5s                           % stack ellipse ROI data for channel 5
        hroi5s                          % current stack ellipse ROI for channel 5
        index5                          % index for 2P ellipse ROI for channel 5
        roi6                            % 2P ellipse ROI data for channel 6
        hroi6                           % current 2P ellipse ROI for channel 6
        roi6s                           % stack ellipse ROI data for channel 6
        hroi6s                          % current stack ellipse ROI for channel 6
        index6                          % index for 2P ellipse ROI for channel 6
        roi7                            % 2P ellipse ROI data for channel 7
        hroi7                           % current 2P ellipse ROI for channel 7
        roi7s                           % stack ellipse ROI data for channel 7
        hroi7s                          % current stack ellipse ROI for channel 7
        index7                          % index for 2P ellipse ROI for channel 7
        roi8                            % 2P ellipse ROI data for channel 8
        hroi8                           % current 2P ellipse ROI for channel 8
        roi8s                           % stack ellipse ROI data for channel 8
        hroi8s                          % current stack ellipse ROI for channel 8
        index8                          % index for 2P ellipse ROI for channel 8
        roi9                            % 2P ellipse ROI data for channel 9
        hroi9                           % current 2P ellipse ROI for channel 9
        roi9s                           % stack ellipse ROI data for channel 9
        hroi9s                          % current stack ellipse ROI for channel 9
        index9                          % index for 2P ellipse ROI for channel 9
        roi10                           % 2P ellipse ROI data for channel 10
        hroi10                          % current 2P ellipse ROI for channel 10
        roi10s                          % stack ellipse ROI data for channel 10
        hroi10s                         % current stack ellipse ROI for channel 10
        index10                         % index for 2P ellipse ROI for channel 10
        l1                              % listener for ROIMoved
        l2                              % listener for DeletingROI
        l3                              % listener for ROIClicked
        output = {}                     % complete output for app

    end

    methods (Access = public)

        function reformat_file_names(app, prefix1, separator1, suffix1, prefix2, separator2, suffix2)
            app.prefix2P = prefix1;                                     % imports new values from file_name_reformatter.mlapp
            app.separator2P = separator1;
            app.suffix2P = suffix1;
            app.prefixStack = prefix2;
            app.separatorStack = separator2;
            app.suffixStack = suffix2;

            app.ReformatFileNamesMenu.Enable = 'on';
        end

        function update_channel_names(app, buttonnames)
            app.buttonnames = buttonnames;                              % imports new values from channel_name_editor.mlapp
            app.DropDown.Items = app.buttonnames;
            app.Channel1Button.Text = app.buttonnames{1};
            app.Channel2Button.Text = app.buttonnames{2};
            app.Channel3Button.Text = app.buttonnames{3};
            app.Channel4Button.Text = app.buttonnames{4};
            app.Channel5Button.Text = app.buttonnames{5};
            app.Channel6Button.Text = app.buttonnames{6};
            app.Channel7Button.Text = app.buttonnames{7};
            app.Channel8Button.Text = app.buttonnames{8};
            app.Channel9Button.Text = app.buttonnames{9};
            app.Channel10Button.Text = app.buttonnames{10};

            app.RenameChannelsMenu.Enable = 'on';
        end

        function ROIallevents1(app,~,evt)                               % ROI events for roi1 on 2P
            evname = evt.EventName;                                     % recieves event from interaction with 2P ROI
            idx = app.index1;
            switch(evname)
                case{'ROIMoved'}                                        % moves ROI
                    
                case{'DeletingROI'}                                     % deletes ROI
                    delete(app.l1{idx});                                % clears listeners
                    delete(app.l2{idx});
                    delete(app.l3{idx});
                    app.Channel1Button.Value = 0;                       % disables drawing mode
                case{'ROIClicked'}                                      % ROI is double clicked to save its dimensions
                    if strcmp(evt.SelectionType, 'double') && ~isempty(app.hroi1{idx})
                        app.roi1{idx}.Center = app.hroi1{idx}.Center;
                        app.roi1{idx}.RotationAngle = app.hroi1{idx}.RotationAngle;
                        app.roi1{idx}.SemiAxes = app.hroi1{idx}.SemiAxes;
                        app.roi1{idx}.Color = app.hroi1{idx}.Color;
                        app.hroi1{idx}.InteractionsAllowed = 'none';    % ROI can no longer be adjusted or deleted after saving
                        app.hroi1s{idx} = drawellipse(app.UIAxes_Stack, 'Center', app.roi1{idx}.Center, 'RotationAngle', app.roi1{idx}.RotationAngle, ...
                            'SemiAxes', app.roi1{idx}.SemiAxes, 'LineWidth', 1, 'Color', 'r', 'FaceAlpha', 0, ...
                            'Label', num2str(app.index1), 'LabelAlpha', 0.2, 'LabelTextColor', 'r');  % equivalent ROI is drawn on stack
                        delete(app.l1{idx});                            % clears listeners
                        delete(app.l2{idx});
                        delete(app.l3{idx});
                        app.l1{idx} = addlistener(app.hroi1s{idx}, 'ROIMoved', @(src, evt)ROIallevents1s(app, app.hroi1s{idx}, evt));   % creates listeners for stack ROI
                        app.l2{idx} = addlistener(app.hroi1s{idx}, 'DeletingROI', @(src, evt)ROIallevents1s(app, app.hroi1s{idx}, evt));
                        app.l3{idx} = addlistener(app.hroi1s{idx}, 'ROIClicked', @(src, evt)ROIallevents1s(app, app.hroi1s{idx}, evt));
                    end
            end
        end

        function ROIallevents1s(app,~,evt)                              % ROI events for roi1 on stack
            evname = evt.EventName;                                     % received event from interaction with stack ROI
            idx = app.index1;
            switch(evname)
                case{'ROIMoved'}                                        % moves ROI
                    
                case{'DeletingROI'}                                     % deletes ROI
                    delete(app.l1{idx});                                % clears listeners
                    delete(app.l2{idx});
                    delete(app.l3{idx});
                    app.Channel1Button.Value = 0;                       % disables drawing mode
                case{'ROIClicked'}                                      % ROI is double clicked to save its dimensions
                    if strcmp(evt.SelectionType, 'double') && ~isempty(app.hroi1s{idx})
                        app.roi1s{idx}.Center = app.hroi1s{idx}.Center;
                        app.roi1s{idx}.RotationAngle = app.hroi1s{idx}.RotationAngle;
                        app.roi1s{idx}.SemiAxes = app.hroi1s{idx}.SemiAxes;
                        app.roi1s{idx}.Color = app.hroi1s{idx}.Color;
                        app.hroi1s{idx}.InteractionsAllowed = 'none';   % ROI can no longer be adjusted or deleted after saving
                        delete(app.l1{idx});                            % clears listeners
                        delete(app.l2{idx});
                        delete(app.l3{idx});
                        app.Channel1Button.Value = 0;                   % disables drawing mode
                    end
            end
        end

        function ROIallevents2(app,~,evt)                               % ROI events for roi2 on 2P
            evname = evt.EventName;
            idx = app.index2;
            switch(evname)
                case{'ROIMoved'}
                    
                case{'DeletingROI'}
                    delete(app.l1{idx});
                    delete(app.l2{idx});
                    delete(app.l3{idx});
                    app.Channel2Button.Value = 0;
                case{'ROIClicked'}
                    if strcmp(evt.SelectionType, 'double') && ~isempty(app.hroi2{idx})
                        app.roi2{idx}.Center = app.hroi2{idx}.Center;
                        app.roi2{idx}.RotationAngle = app.hroi2{idx}.RotationAngle;
                        app.roi2{idx}.SemiAxes = app.hroi2{idx}.SemiAxes;
                        app.roi2{idx}.Color = app.hroi2{idx}.Color;
                        app.hroi2{idx}.InteractionsAllowed = 'none';
                        app.hroi2s{idx} = drawellipse(app.UIAxes_Stack, 'Center', app.roi2{idx}.Center, 'RotationAngle', app.roi2{idx}.RotationAngle, ...
                            'SemiAxes', app.roi2{idx}.SemiAxes, 'LineWidth', 1, 'Color', 'g', 'FaceAlpha', 0, ...
                            'Label', num2str(app.index1), 'LabelAlpha', 0.2, 'LabelTextColor', 'g');
                        delete(app.l1{idx});
                        delete(app.l2{idx});
                        delete(app.l3{idx});
                        app.l1{idx} = addlistener(app.hroi2s{idx}, 'ROIMoved', @(src, evt)ROIallevents2s(app, app.hroi2s{idx}, evt));
                        app.l2{idx} = addlistener(app.hroi2s{idx}, 'DeletingROI', @(src, evt)ROIallevents2s(app, app.hroi2s{idx}, evt));
                        app.l3{idx} = addlistener(app.hroi2s{idx}, 'ROIClicked', @(src, evt)ROIallevents2s(app, app.hroi2s{idx}, evt));
                    end
            end
        end

        function ROIallevents2s(app,~,evt)                              % ROI events for roi2 on stack
            evname = evt.EventName;
            idx = app.index2;
            switch(evname)
                case{'ROIMoved'}
                    
                case{'DeletingROI'}
                    delete(app.l1{idx});
                    delete(app.l2{idx});
                    delete(app.l3{idx});
                    app.Channel2Button.Value = 0;
                case{'ROIClicked'}
                    if strcmp(evt.SelectionType, 'double') && ~isempty(app.hroi2s{idx})
                        app.roi2s{idx}.Center = app.hroi2s{idx}.Center;
                        app.roi2s{idx}.RotationAngle = app.hroi2s{idx}.RotationAngle;
                        app.roi2s{idx}.SemiAxes = app.hroi2s{idx}.SemiAxes;
                        app.roi2s{idx}.Color = app.hroi2s{idx}.Color;
                        app.hroi2s{idx}.InteractionsAllowed = 'none';
                        delete(app.l1{idx});
                        delete(app.l2{idx});
                        delete(app.l3{idx});
                        app.Channel2Button.Value = 0;
                    end
            end
        end

        function ROIallevents3(app,~,evt)                               % ROI events for roi3 on 2P
            evname = evt.EventName;
            idx = app.index3;
            switch(evname)
                case{'ROIMoved'}
                    
                case{'DeletingROI'}
                    delete(app.l1{idx});
                    delete(app.l2{idx});
                    delete(app.l3{idx});
                    app.Channel3Button.Value = 0;
                case{'ROIClicked'}
                    if strcmp(evt.SelectionType, 'double') && ~isempty(app.hroi3{idx})
                        app.roi3{idx}.Center = app.hroi3{idx}.Center;
                        app.roi3{idx}.RotationAngle = app.hroi3{idx}.RotationAngle;
                        app.roi3{idx}.SemiAxes = app.hroi3{idx}.SemiAxes;
                        app.roi3{idx}.Color = app.hroi3{idx}.Color;
                        app.hroi3{app.index3}.InteractionsAllowed = 'none';
                        app.hroi3s{idx} = drawellipse(app.UIAxes_Stack, 'Center', app.roi3{idx}.Center, 'RotationAngle', app.roi3{idx}.RotationAngle, ...
                            'SemiAxes', app.roi3{idx}.SemiAxes, 'LineWidth', 1, 'Color', 'b', 'FaceAlpha', 0, ...
                            'Label', num2str(app.index1), 'LabelAlpha', 0.2, 'LabelTextColor', 'b');
                        delete(app.l1{idx});
                        delete(app.l2{idx});
                        delete(app.l3{idx});
                        app.l1{idx} = addlistener(app.hroi3s{idx}, 'ROIMoved', @(src, evt)ROIallevents3s(app, app.hroi3s{idx}, evt));
                        app.l2{idx} = addlistener(app.hroi3s{idx}, 'DeletingROI', @(src, evt)ROIallevents3s(app, app.hroi3s{idx}, evt));
                        app.l3{idx} = addlistener(app.hroi3s{idx}, 'ROIClicked', @(src, evt)ROIallevents3s(app, app.hroi3s{idx}, evt));
                    end
            end
        end

        function ROIallevents3s(app,~,evt)                              % ROI events for roi3 on stack
            evname = evt.EventName;
            idx = app.index3;
            switch(evname)
                case{'ROIMoved'}
                    
                case{'DeletingROI'}
                    delete(app.l1{idx});
                    delete(app.l2{idx});
                    delete(app.l3{idx});
                    app.Channel3Button.Value = 0;
                case{'ROIClicked'}
                    if strcmp(evt.SelectionType, 'double') && ~isempty(app.hroi3s{idx})
                        app.roi3s{idx}.Center = app.hroi3s{idx}.Center;
                        app.roi3s{idx}.RotationAngle = app.hroi3s{idx}.RotationAngle;
                        app.roi3s{idx}.SemiAxes = app.hroi3s{idx}.SemiAxes;
                        app.roi3s{idx}.Color = app.hroi3s{idx}.Color;
                        app.hroi3s{idx}.InteractionsAllowed = 'none';
                        delete(app.l1{idx});
                        delete(app.l2{idx});
                        delete(app.l3{idx});
                        app.Channel3Button.Value = 0;
                    end
            end
        end

        function ROIallevents4(app,~,evt)                               % ROI events for roi4 on 2P
            evname = evt.EventName;
            idx = app.index4;
            switch(evname)
                case{'ROIMoved'}
                    
                case{'DeletingROI'}
                    delete(app.l1{idx});
                    delete(app.l2{idx});
                    delete(app.l3{idx});
                    app.Channel4Button.Value = 0;
                case{'ROIClicked'}
                    if strcmp(evt.SelectionType, 'double') && ~isempty(app.hroi4{idx})
                        app.roi4{idx}.Center = app.hroi4{idx}.Center;
                        app.roi4{idx}.RotationAngle = app.hroi4{idx}.RotationAngle;
                        app.roi4{idx}.SemiAxes = app.hroi4{idx}.SemiAxes;
                        app.roi4{idx}.Color = app.hroi4{idx}.Color;
                        app.hroi4{idx}.InteractionsAllowed = 'none';
                        app.hroi4s{idx} = drawellipse(app.UIAxes_Stack, 'Center', app.roi4{idx}.Center, 'RotationAngle', app.roi4{idx}.RotationAngle, ...
                            'SemiAxes', app.roi4{idx}.SemiAxes, 'LineWidth', 1, 'Color', 'c', 'FaceAlpha', 0, ...
                            'Label', num2str(app.index1), 'LabelAlpha', 0.2, 'LabelTextColor', 'c');
                        delete(app.l1{idx});
                        delete(app.l2{idx});
                        delete(app.l3{idx});
                        app.l1{idx} = addlistener(app.hroi4s{idx}, 'ROIMoved', @(src, evt)ROIallevents4s(app, app.hroi4s{idx}, evt));
                        app.l2{idx} = addlistener(app.hroi4s{idx}, 'DeletingROI', @(src, evt)ROIallevents4s(app, app.hroi4s{idx}, evt));
                        app.l3{idx} = addlistener(app.hroi4s{idx}, 'ROIClicked', @(src, evt)ROIallevents4s(app, app.hroi4s{idx}, evt));
                    end
            end
        end

        function ROIallevents4s(app,~,evt)                              % ROI events for roi4 on stack
            evname = evt.EventName;
            idx = app.index4;
            switch(evname)
                case{'ROIMoved'}
                    
                case{'DeletingROI'}
                    delete(app.l1{idx});
                    delete(app.l2{idx});
                    delete(app.l3{idx});
                    app.Channel4Button.Value = 0;
                case{'ROIClicked'}
                    if strcmp(evt.SelectionType, 'double') && ~isempty(app.hroi4s{idx})
                        app.roi4s{idx}.Center = app.hroi4s{idx}.Center;
                        app.roi4s{idx}.RotationAngle = app.hroi4s{idx}.RotationAngle;
                        app.roi4s{idx}.SemiAxes = app.hroi4s{idx}.SemiAxes;
                        app.roi4s{idx}.Color = app.hroi4s{idx}.Color;
                        app.hroi4s{idx}.InteractionsAllowed = 'none';
                        delete(app.l1{idx});
                        delete(app.l2{idx});
                        delete(app.l3{idx});
                        app.Channel4Button.Value = 0;
                    end
            end
        end

        function ROIallevents5(app,~,evt)                               % ROI events for roi5 on 2P
            evname = evt.EventName;
            idx = app.index5;
            switch(evname)
                case{'ROIMoved'}
                    
                case{'DeletingROI'}
                    delete(app.l1{idx});
                    delete(app.l2{idx});
                    delete(app.l3{idx});
                    app.Channel5Button.Value = 0;
                case{'ROIClicked'}
                    if strcmp(evt.SelectionType, 'double') && ~isempty(app.hroi5{idx})
                        app.roi5{idx}.Center = app.hroi5{idx}.Center;
                        app.roi5{idx}.RotationAngle = app.hroi5{idx}.RotationAngle;
                        app.roi5{idx}.SemiAxes = app.hroi5{idx}.SemiAxes;
                        app.roi5{idx}.Color = app.hroi5{idx}.Color;
                        app.hroi5{idx}.InteractionsAllowed = 'none';
                        app.hroi5s{idx} = drawellipse(app.UIAxes_Stack, 'Center', app.roi5{idx}.Center, 'RotationAngle', app.roi5{idx}.RotationAngle, ...
                            'SemiAxes', app.roi5{idx}.SemiAxes, 'LineWidth', 1, 'Color', 'm', 'FaceAlpha', 0, ...
                            'Label', num2str(app.index1), 'LabelAlpha', 0.2, 'LabelTextColor', 'm');
                        delete(app.l1{idx});
                        delete(app.l2{idx});
                        delete(app.l3{idx});
                        app.l1{idx} = addlistener(app.hroi5s{idx}, 'ROIMoved', @(src, evt)ROIallevents5s(app, app.hroi5s{idx}, evt));
                        app.l2{idx} = addlistener(app.hroi5s{idx}, 'DeletingROI', @(src, evt)ROIallevents5s(app, app.hroi5s{idx}, evt));
                        app.l3{idx} = addlistener(app.hroi5s{idx}, 'ROIClicked', @(src, evt)ROIallevents5s(app, app.hroi5s{idx}, evt));
                    end
            end
        end

        function ROIallevents5s(app,~,evt)                              % ROI events for roi5 on stack
            evname = evt.EventName;
            idx = app.index5;
            switch(evname)
                case{'ROIMoved'}
                    
                case{'DeletingROI'}
                    delete(app.l1{idx});
                    delete(app.l2{idx});
                    delete(app.l3{idx});
                    app.Channel5Button.Value = 0;
                case{'ROIClicked'}
                    if strcmp(evt.SelectionType, 'double') && ~isempty(app.hroi5s{idx})
                        app.roi5s{idx}.Center = app.hroi5s{idx}.Center;
                        app.roi5s{idx}.RotationAngle = app.hroi5s{idx}.RotationAngle;
                        app.roi5s{idx}.SemiAxes = app.hroi5s{idx}.SemiAxes;
                        app.roi5s{idx}.Color = app.hroi5s{idx}.Color;
                        app.hroi5s{idx}.InteractionsAllowed = 'none';
                        delete(app.l1{idx});
                        delete(app.l2{idx});
                        delete(app.l3{idx});
                        app.Channel5Button.Value = 0;
                    end
            end
        end

        function ROIallevents6(app,~,evt)                               % ROI events for roi6 on 2P
            evname = evt.EventName;
            idx = app.index6;
            switch(evname)
                case{'ROIMoved'}
                    
                case{'DeletingROI'}
                    delete(app.l1{idx});
                    delete(app.l2{idx});
                    delete(app.l3{idx});
                    app.Channel6Button.Value = 0;
                case{'ROIClicked'}
                    if strcmp(evt.SelectionType, 'double') && ~isempty(app.hroi6{idx})
                        app.roi6{idx}.Center = app.hroi6{idx}.Center;
                        app.roi6{idx}.RotationAngle = app.hroi6{idx}.RotationAngle;
                        app.roi6{idx}.SemiAxes = app.hroi6{idx}.SemiAxes;
                        app.roi6{idx}.Color = app.hroi6{idx}.Color;
                        app.hroi6{idx}.InteractionsAllowed = 'none';
                        app.hroi6s{idx} = drawellipse(app.UIAxes_Stack, 'Center', app.roi6{idx}.Center, 'RotationAngle', app.roi6{idx}.RotationAngle, ...
                            'SemiAxes', app.roi6{idx}.SemiAxes, 'LineWidth', 1, 'Color', 'y', 'FaceAlpha', 0, ...
                            'Label', num2str(app.index1), 'LabelAlpha', 0.2, 'LabelTextColor', 'y');
                        delete(app.l1{idx});
                        delete(app.l2{idx});
                        delete(app.l3{idx});
                        app.l1{idx} = addlistener(app.hroi6s{idx}, 'ROIMoved', @(src, evt)ROIallevents6s(app, app.hroi6s{idx}, evt));
                        app.l2{idx} = addlistener(app.hroi6s{idx}, 'DeletingROI', @(src, evt)ROIallevents6s(app, app.hroi6s{idx}, evt));
                        app.l3{idx} = addlistener(app.hroi6s{idx}, 'ROIClicked', @(src, evt)ROIallevents6s(app, app.hroi6s{idx}, evt));
                    end
            end
        end

        function ROIallevents6s(app,~,evt)                              % ROI events for roi16 on stack
            evname = evt.EventName;
            idx = app.index6;
            switch(evname)
                case{'ROIMoved'}
                    
                case{'DeletingROI'}
                    delete(app.l1{idx});
                    delete(app.l2{idx});
                    delete(app.l3{idx});
                    app.Channel6Button.Value = 0;
                case{'ROIClicked'}
                    if strcmp(evt.SelectionType, 'double') && ~isempty(app.hroi6s{idx})
                        app.roi6s{idx}.Center = app.hroi6s{idx}.Center;
                        app.roi6s{idx}.RotationAngle = app.hroi6s{idx}.RotationAngle;
                        app.roi6s{idx}.SemiAxes = app.hroi6s{idx}.SemiAxes;
                        app.roi6s{idx}.Color = app.hroi6s{idx}.Color;
                        app.hroi6s{idx}.InteractionsAllowed = 'none';
                        delete(app.l1{idx});
                        delete(app.l2{idx});
                        delete(app.l3{idx});
                        app.Channel6Button.Value = 0;
                    end
            end
        end

        function ROIallevents7(app,~,evt)                               % ROI events for roi7 on 2P
            evname = evt.EventName;
            idx = app.index7;
            switch(evname)
                case{'ROIMoved'}
                    
                case{'DeletingROI'}
                    delete(app.l1{idx});
                    delete(app.l2{idx});
                    delete(app.l3{idx});
                    app.Channel7Button.Value = 0;
                case{'ROIClicked'}
                    if strcmp(evt.SelectionType, 'double') && ~isempty(app.hroi7{idx})
                        app.roi7{idx}.Center = app.hroi7{idx}.Center;
                        app.roi7{idx}.RotationAngle = app.hroi7{idx}.RotationAngle;
                        app.roi7{idx}.SemiAxes = app.hroi7{idx}.SemiAxes;
                        app.roi7{idx}.Color = app.hroi7{idx}.Color;
                        app.hroi7{idx}.InteractionsAllowed = 'none';
                        app.hroi7s{idx} = drawellipse(app.UIAxes_Stack, 'Center', app.roi7{idx}.Center, 'RotationAngle', app.roi7{idx}.RotationAngle, ...
                            'SemiAxes', app.roi7{idx}.SemiAxes, 'LineWidth', 1, 'Color', 'k', 'FaceAlpha', 0, ...
                            'Label', num2str(app.index1), 'LabelAlpha', 0.2, 'LabelTextColor', 'k');
                        delete(app.l1{idx});
                        delete(app.l2{idx});
                        delete(app.l3{idx});
                        app.l1{idx} = addlistener(app.hroi7s{idx}, 'ROIMoved', @(src, evt)ROIallevents7s(app, app.hroi7s{idx}, evt));
                        app.l2{idx} = addlistener(app.hroi7s{idx}, 'DeletingROI', @(src, evt)ROIallevents7s(app, app.hroi7s{idx}, evt));
                        app.l3{idx} = addlistener(app.hroi7s{idx}, 'ROIClicked', @(src, evt)ROIallevents7s(app, app.hroi7s{idx}, evt));
                    end
            end
        end

        function ROIallevents7s(app,~,evt)                              % ROI events for roi7 on stack
            evname = evt.EventName;
            idx = app.index7;
            switch(evname)
                case{'ROIMoved'}
                    
                case{'DeletingROI'}
                    delete(app.l1{idx});
                    delete(app.l2{idx});
                    delete(app.l3{idx});
                    app.Channel7Button.Value = 0;
                case{'ROIClicked'}
                    if strcmp(evt.SelectionType, 'double') && ~isempty(app.hroi7s{idx})
                        app.roi7s{idx}.Center = app.hroi7s{idx}.Center;
                        app.roi7s{idx}.RotationAngle = app.hroi7s{idx}.RotationAngle;
                        app.roi7s{idx}.SemiAxes = app.hroi7s{idx}.SemiAxes;
                        app.roi7s{idx}.Color = app.hroi7s{idx}.Color;
                        app.hroi7s{idx}.InteractionsAllowed = 'none';
                        delete(app.l1{idx});
                        delete(app.l2{idx});
                        delete(app.l3{idx});
                        app.Channel7Button.Value = 0;
                    end
            end
        end

        function ROIallevents8(app,~,evt)                               % ROI events for roi8 on 2P
            evname = evt.EventName;
            idx = app.index8;
            switch(evname)
                case{'ROIMoved'}
                    
                case{'DeletingROI'}
                    delete(app.l1{idx});
                    delete(app.l2{idx});
                    delete(app.l3{idx});
                    app.Channel8Button.Value = 0;
                case{'ROIClicked'}
                    if strcmp(evt.SelectionType, 'double') && ~isempty(app.hroi8{idx})
                        app.roi8{idx}.Center = app.hroi8{idx}.Center;
                        app.roi8{idx}.RotationAngle = app.hroi8{idx}.RotationAngle;
                        app.roi8{idx}.SemiAxes = app.hroi8{idx}.SemiAxes;
                        app.roi8{idx}.Color = app.hroi8{idx}.Color;
                        app.hroi8{idx}.InteractionsAllowed = 'none';
                        app.hroi8s{idx} = drawellipse(app.UIAxes_Stack, 'Center', app.roi8{idx}.Center, 'RotationAngle', app.roi8{idx}.RotationAngle, ...
                            'SemiAxes', app.roi8{idx}.SemiAxes, 'LineWidth', 1, 'Color', 'w', 'FaceAlpha', 0, ...
                            'Label', num2str(app.index1), 'LabelAlpha', 0.2, 'LabelTextColor', 'w');
                        delete(app.l1{idx});
                        delete(app.l2{idx});
                        delete(app.l3{idx});
                        app.l1{idx} = addlistener(app.hroi8s{idx}, 'ROIMoved', @(src, evt)ROIallevents8s(app, app.hroi8s{idx}, evt));
                        app.l2{idx} = addlistener(app.hroi8s{idx}, 'DeletingROI', @(src, evt)ROIallevents8s(app, app.hroi8s{idx}, evt));
                        app.l3{idx} = addlistener(app.hroi8s{idx}, 'ROIClicked', @(src, evt)ROIallevents8s(app, app.hroi8s{idx}, evt));
                    end
            end
        end

        function ROIallevents8s(app,~,evt)                              % ROI events for roi8 on stack
            evname = evt.EventName;
            idx = app.index8;
            switch(evname)
                case{'ROIMoved'}
                    
                case{'DeletingROI'}
                    delete(app.l1{idx});
                    delete(app.l2{idx});
                    delete(app.l3{idx});
                    app.Channel8Button.Value = 0;
                case{'ROIClicked'}
                    if strcmp(evt.SelectionType, 'double') && ~isempty(app.hroi8s{idx})
                        app.roi8s{idx}.Center = app.hroi8s{idx}.Center;
                        app.roi8s{idx}.RotationAngle = app.hroi8s{idx}.RotationAngle;
                        app.roi8s{idx}.SemiAxes = app.hroi8s{idx}.SemiAxes;
                        app.roi8s{idx}.Color = app.hroi8s{idx}.Color;
                        app.hroi8s{idx}.InteractionsAllowed = 'none';
                        delete(app.l1{idx});
                        delete(app.l2{idx});
                        delete(app.l3{idx});
                        app.Channel8Button.Value = 0;
                    end
            end
        end

        function ROIallevents9(app,~,evt)                               % ROI events for roi9 on 2P
            evname = evt.EventName;
            idx = app.index9;
            switch(evname)
                case{'ROIMoved'}
                    
                case{'DeletingROI'}
                    delete(app.l1{idx});
                    delete(app.l2{idx});
                    delete(app.l3{idx});
                    app.Channel9Button.Value = 0;
                case{'ROIClicked'}
                    if strcmp(evt.SelectionType, 'double') && ~isempty(app.hroi9{idx})
                        app.roi9{idx}.Center = app.hroi9{idx}.Center;
                        app.roi9{idx}.RotationAngle = app.hroi9{idx}.RotationAngle;
                        app.roi9{idx}.SemiAxes = app.hroi9{idx}.SemiAxes;
                        app.roi9{idx}.Color = app.hroi9{idx}.Color;
                        app.hroi9{idx}.InteractionsAllowed = 'none';
                        app.hroi9s{idx} = drawellipse(app.UIAxes_Stack, 'Center', app.roi9{idx}.Center, 'RotationAngle', app.roi9{idx}.RotationAngle, ...
                            'SemiAxes', app.roi9{idx}.SemiAxes, 'LineWidth', 1, 'Color', [0.6350 0.0780 0.1840], 'FaceAlpha', 0, ...
                            'Label', num2str(app.index1), 'LabelAlpha', 0.2, 'LabelTextColor', [0.6350 0.0780 0.1840]);
                        delete(app.l1{idx});
                        delete(app.l2{idx});
                        delete(app.l3{idx});
                        app.l1{idx} = addlistener(app.hroi9s{idx}, 'ROIMoved', @(src, evt)ROIallevents9s(app, app.hroi9s{idx}, evt));
                        app.l2{idx} = addlistener(app.hroi9s{idx}, 'DeletingROI', @(src, evt)ROIallevents9s(app, app.hroi9s{idx}, evt));
                        app.l3{idx} = addlistener(app.hroi9s{idx}, 'ROIClicked', @(src, evt)ROIallevents9s(app, app.hroi9s{idx}, evt));
                    end
            end
        end

        function ROIallevents9s(app,~,evt)                              % ROI events for roi9 on stack
            evname = evt.EventName;
            idx = app.index9;
            switch(evname)
                case{'ROIMoved'}
                    
                case{'DeletingROI'}
                    delete(app.l1{idx});
                    delete(app.l2{idx});
                    delete(app.l3{idx});
                    app.Channel9Button.Value = 0;
                case{'ROIClicked'}
                    if strcmp(evt.SelectionType, 'double') && ~isempty(app.hroi9s{idx})
                        app.roi9s{idx}.Center = app.hroi9s{idx}.Center;
                        app.roi9s{idx}.RotationAngle = app.hroi9s{idx}.RotationAngle;
                        app.roi9s{idx}.SemiAxes = app.hroi9s{idx}.SemiAxes;
                        app.roi9s{idx}.Color = app.hroi9s{idx}.Color;
                        app.hroi9s{idx}.InteractionsAllowed = 'none';
                        delete(app.l1{idx});
                        delete(app.l2{idx});
                        delete(app.l3{idx});
                        app.Channel9Button.Value = 0;
                    end
            end
        end

        function ROIallevents10(app,~,evt)                              % ROI events for roi10 on 2P
            evname = evt.EventName;
            idx = app.index10;
            switch(evname)
                case{'ROIMoved'}
                    
                case{'DeletingROI'}
                    delete(app.l1{idx});
                    delete(app.l2{idx});
                    delete(app.l3{idx});
                    app.Channel10Button.Value = 0;
                case{'ROIClicked'}
                    if strcmp(evt.SelectionType, 'double') && ~isempty(app.hroi10{idx})
                        app.roi10{idx}.Center = app.hroi10{idx}.Center;
                        app.roi10{idx}.RotationAngle = app.hroi10{idx}.RotationAngle;
                        app.roi10{idx}.SemiAxes = app.hroi10{idx}.SemiAxes;
                        app.roi10{idx}.Color = app.hroi10{idx}.Color;
                        app.hroi10{idx}.InteractionsAllowed = 'none';
                        app.hroi10s{idx} = drawellipse(app.UIAxes_Stack, 'Center', app.roi10{idx}.Center, 'RotationAngle', app.roi10{idx}.RotationAngle, ...
                            'SemiAxes', app.roi10{idx}.SemiAxes, 'LineWidth', 1, 'Color', [0 0.4470 0.7410], 'FaceAlpha', 0, ...
                            'Label', num2str(app.index1), 'LabelAlpha', 0.2, 'LabelTextColor', [0 0.4470 0.7410]);
                        delete(app.l1{idx});
                        delete(app.l2{idx});
                        delete(app.l3{idx});
                        app.l1{idx} = addlistener(app.hroi10s{idx}, 'ROIMoved', @(src, evt)ROIallevents10s(app, app.hroi10s{idx}, evt));
                        app.l2{idx} = addlistener(app.hroi10s{idx}, 'DeletingROI', @(src, evt)ROIallevents10s(app, app.hroi10s{idx}, evt));
                        app.l3{idx} = addlistener(app.hroi10s{idx}, 'ROIClicked', @(src, evt)ROIallevents10s(app, app.hroi10s{idx}, evt));
                    end
            end
        end

        function ROIallevents10s(app,~,evt)                             % ROI events for roi10 on stack
            evname = evt.EventName;
            idx = app.index10;
            switch(evname)
                case{'ROIMoved'}
                    
                case{'DeletingROI'}
                    delete(app.l1{idx});
                    delete(app.l2{idx});
                    delete(app.l3{idx});
                    app.Channel10Button.Value = 0;
                case{'ROIClicked'}
                    if strcmp(evt.SelectionType, 'double') && ~isempty(app.hroi10s{idx})
                        app.roi10s{idx}.Center = app.hroi10s{idx}.Center;
                        app.roi10s{idx}.RotationAngle = app.hroi10s{idx}.RotationAngle;
                        app.roi10s{idx}.SemiAxes = app.hroi10s{idx}.SemiAxes;
                        app.roi10s{idx}.Color = app.hroi10s{idx}.Color;
                        app.hroi10s{idx}.InteractionsAllowed = 'none';
                        delete(app.l1{idx});
                        delete(app.l2{idx});
                        delete(app.l3{idx});
                        app.Channel10Button.Value = 0;
                    end
            end
        end

        function overlay_ROI(app)                                       % draw ROI on UIAxes_Stack (maintains position when changing frames)
            cla(app.UIAxes_Stack);
            fS= imagesc(app.UIAxes_Stack, squeeze(app.image_stack(round(app.ChannelSlider.Value),:,:,:)));
            app.UIAxes_Stack.XLim = [0 fS.XData(2)];
            app.UIAxes_Stack.YLim = [0 fS.YData(2)];
            if ~isempty(app.roi1s)
                for i=1:length(app.roi1s)
                    drawellipse(app.UIAxes_Stack, 'Center', app.roi1s{i}.Center, 'RotationAngle', app.roi1s{i}.RotationAngle, ...
                        'SemiAxes', app.roi1s{i}.SemiAxes, 'LineWidth', 1, 'Color', 'r', 'FaceAlpha', 0, 'InteractionsAllowed', 'none', ...
                        'Label', num2str(i), 'LabelAlpha', 0.2, 'LabelTextColor', 'r');
                end
            end
            if ~isempty(app.roi2s)
                for i=1:length(app.roi2s)
                    drawellipse(app.UIAxes_Stack, 'Center', app.roi2s{i}.Center, 'RotationAngle', app.roi2s{i}.RotationAngle,...
                        'SemiAxes', app.roi2s{i}.SemiAxes, 'LineWidth', 1, 'Color', 'g', 'FaceAlpha', 0, 'InteractionsAllowed', 'none', ...
                        'Label', num2str(i), 'LabelAlpha', 0.2, 'LabelTextColor', 'g');
                end
            end
            if ~isempty(app.roi3s)
                for i=1:length(app.roi3s)
                    drawellipse(app.UIAxes_Stack, 'Center', app.roi3s{i}.Center, 'RotationAngle', app.roi3s{i}.RotationAngle,...
                        'SemiAxes', app.roi3s{i}.SemiAxes, 'LineWidth', 1, 'Color', 'b', 'FaceAlpha', 0, 'InteractionsAllowed', 'none', ...
                        'Label', num2str(i), 'LabelAlpha', 0.2, 'LabelTextColor', 'b');
                end
            end
            if ~isempty(app.roi4s)
                for i=1:length(app.roi4s)
                    drawellipse(app.UIAxes_Stack, 'Center', app.roi4s{i}.Center, 'RotationAngle', app.roi4s{i}.RotationAngle,...
                        'SemiAxes', app.roi4s{i}.SemiAxes, 'LineWidth', 1, 'Color', 'c', 'FaceAlpha', 0, 'InteractionsAllowed', 'none', ...
                        'Label', num2str(i), 'LabelAlpha', 0.2, 'LabelTextColor', 'c');
                end
            end
            if ~isempty(app.roi5s)
                for i=1:length(app.roi5s)
                    drawellipse(app.UIAxes_Stack, 'Center', app.roi5s{i}.Center, 'RotationAngle', app.roi5s{i}.RotationAngle,...
                        'SemiAxes', app.roi5s{i}.SemiAxes, 'LineWidth', 1, 'Color', 'm', 'FaceAlpha', 0, 'InteractionsAllowed', 'none', ...
                        'Label', num2str(i), 'LabelAlpha', 0.2, 'LabelTextColor', 'm');
                end
            end
            if ~isempty(app.roi6s)
                for i=1:length(app.roi6s)
                    drawellipse(app.UIAxes_Stack, 'Center', app.roi6s{i}.Center, 'RotationAngle', app.roi6s{i}.RotationAngle,...
                        'SemiAxes', app.roi6s{i}.SemiAxes, 'LineWidth', 1, 'Color', 'y', 'FaceAlpha', 0, 'InteractionsAllowed', 'none', ...
                        'Label', num2str(i), 'LabelAlpha', 0.2, 'LabelTextColor', 'y');
                end
            end
            if ~isempty(app.roi7s)
                for i=1:length(app.roi7s)
                    drawellipse(app.UIAxes_Stack, 'Center', app.roi7s{i}.Center, 'RotationAngle', app.roi7s{i}.RotationAngle,...
                        'SemiAxes', app.roi7s{i}.SemiAxes, 'LineWidth', 1, 'Color', 'k', 'FaceAlpha', 0, 'InteractionsAllowed', 'none', ...
                        'Label', num2str(i), 'LabelAlpha', 0.2, 'LabelTextColor', 'k');
                end
            end
            if ~isempty(app.roi8s)
                for i=1:length(app.roi8s)
                    drawellipse(app.UIAxes_Stack, 'Center', app.roi8s{i}.Center, 'RotationAngle', app.roi8s{i}.RotationAngle,...
                        'SemiAxes', app.roi8s{i}.SemiAxes, 'LineWidth', 1, 'Color', 'w', 'FaceAlpha', 0, 'InteractionsAllowed', 'none', ...
                        'Label', num2str(i), 'LabelAlpha', 0.2, 'LabelTextColor', 'w');
                end
            end
            if ~isempty(app.roi9s)
                for i=1:length(app.roi9s)
                    drawellipse(app.UIAxes_Stack, 'Center', app.roi9s{i}.Center, 'RotationAngle', app.roi9s{i}.RotationAngle,...
                        'SemiAxes', app.roi9s{i}.SemiAxes, 'LineWidth', 1, 'Color', [0.6350 0.0780 0.1840], 'FaceAlpha', 0, 'InteractionsAllowed', 'none', ...
                        'Label', num2str(i), 'LabelAlpha', 0.2, 'LabelTextColor', [0.6350 0.0780 0.1840]);
                end
            end
            if ~isempty(app.roi10s)
                for i=1:length(app.roi10s)
                    drawellipse(app.UIAxes_Stack, 'Center', app.roi10s{i}.Center, 'RotationAngle', app.roi10s{i}.RotationAngle,...
                        'SemiAxes', app.roi10s{i}.SemiAxes, 'LineWidth', 1, 'Color', [0 0.4470 0.7410], 'FaceAlpha', 0, 'InteractionsAllowed', 'none', ...
                        'Label', num2str(i), 'LabelAlpha', 0.2, 'LabelTextColor', [0 0.4470 0.7410]);
                end
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            if exist('channel_names.mat', 'file')                       % names channels if preset exists
                app.buttonnames = load('channel_names.mat');
                app.buttonnames = app.buttonnames.channel_names;
                app.renameapp.DropDown.Items = app.buttonnames;
                app.Channel1Button.Text = app.buttonnames{1};
                app.Channel2Button.Text = app.buttonnames{2};
                app.Channel3Button.Text = app.buttonnames{3};
                app.Channel4Button.Text = app.buttonnames{4};
                app.Channel5Button.Text = app.buttonnames{5};
                app.Channel6Button.Text = app.buttonnames{6};
                app.Channel7Button.Text = app.buttonnames{7};
                app.Channel8Button.Text = app.buttonnames{8};
                app.Channel9Button.Text = app.buttonnames{9};
                app.Channel10Button.Text = app.buttonnames{10};
            else                                                        % uses defaults names if no preset exists
                app.Channel1Button.Text = 'Tau';
                app.Channel2Button.Text = 'Amyloid';
                app.Channel3Button.Text = 'Cell-type identification';
                app.Channel4Button.Text = 'Myelin-oligodendrocyte';
                app.Channel5Button.Text = 'Microvascular changes';
                app.buttonnames = {app.Channel1Button.Text, app.Channel2Button.Text, app.Channel3Button.Text, app.Channel4Button.Text, app.Channel5Button.Text,...
                    app.Channel6Button.Text, app.Channel7Button.Text, app.Channel8Button.Text, app.Channel9Button.Text, app.Channel10Button.Text};
                app.DropDown.Items = app.buttonnames;
            end

        end

        % Menu selected function: OpenMenu
        function OpenMenuSelected(app, event)
            clc;                                                        % clears axes
            cla(app.UIAxes_2P);
            cla(app.UIAxes_Stack);

            app.roi1 = {};                                              % (re)initialises variables
            app.hroi1 = {};
            app.roi1s = {};
            app.hroi1s = {};
            app.index1 = 0;
            app.roi2 = {};
            app.hroi2 = {};
            app.roi2s = {};
            app.hroi2s = {};
            app.index2 = 0;
            app.roi3 = {};
            app.hroi3 = {};
            app.roi3s = {};
            app.hroi3s = {};
            app.index3 = 0;
            app.roi4 = {};
            app.hroi4 = {};
            app.roi4s = {};
            app.hroi4s = {};
            app.index4 = 0;
            app.roi5 = {};
            app.hroi5 = {};
            app.roi5s = {};
            app.hroi5s = {};
            app.index5 = 0;
            app.roi6 = {};
            app.hroi6 = {};
            app.roi6s = {};
            app.hroi6s = {};
            app.index6 = 0;
            app.roi7 = {};
            app.hroi7 = {};
            app.roi7s = {};
            app.hroi7s = {};
            app.index7 = 0;
            app.roi8 = {};
            app.hroi8 = {};
            app.roi8s = {};
            app.hroi8s = {};
            app.index8 = 0;
            app.roi9 = {};
            app.hroi9 = {};
            app.roi9s = {};
            app.hroi9s = {};
            app.index9 = 0;
            app.roi10 = {};
            app.hroi10 = {};
            app.roi10s = {};
            app.hroi10s = {};
            app.index10 = 0;

            home = pwd;
            if ~exist([pwd 'annotated_ellipse'],'dir')                  % creates directory for results
                mkdir annotated_ellipse;
            end


            [file, path] = uigetfile('*.tif', 'Select a file');         % select and load 2P image
            if file ~= 0
                tile_seg = strsplit(file, app.separator2P);
                cd(path)                                                % 2P image and stack must be in this same directory

                if ~isempty(app.prefix2P) && strcmp(tile_seg{1}, app.prefix2P)  % constant prefix, variable suffix
                    app.tilename = file;
                    app.image_annotate = imread(app.tilename);
                    f = imagesc(app.UIAxes_2P, app.image_annotate);
                    app.UIAxes_2P.Colormap = gray;
                    app.UIAxes_2P.XLim = [0 f.XData(2)];                % scale to axes
                    app.UIAxes_2P.YLim = [0 f.YData(2)];

                    app.stackname = strsplit(app.tilename, app.separatorStack);
                    app.stackname = strcat(app.prefixStack, app.separatorStack, app.stackname{2});
                    if exist(app.stackname, 'file')                     % retrieves corresponding stack based on 2P's file prefix
                        info = imfinfo(app.stackname);
                        for i = 1:size(info, 1)                         % loads in all frames of stack
                            if i == 1
                                temp = imread(app.stackname, 1);
                                if size(temp,3) == 1
                                    app.image_stack = zeros(size(info, 1), size(temp, 1), size(temp, 2));
                                    app.image_stack(i,:,:) = temp;
                                elseif size(temp,3) == 3
                                    temp = im2uint8(temp);
                                    app.image_stack = uint8(zeros(size(info, 1), size(temp, 1), size(temp, 2), 3));
                                    app.image_stack(i,:,:,:) = temp;
                                end
                            else
                                temp = imread(app.stackname, i);
                                if size(temp,3) == 1
                                    app.image_stack(i,:,:) = temp;
                                elseif size(temp,3) == 3
                                    temp = im2uint8(temp);
                                    app.image_stack(i,:,:,:) = temp;
                                end
                            end
                        end

                        if size(temp,3) == 1
                            fS = imagesc(app.UIAxes_Stack, squeeze(app.image_stack(1,:,:,:)));
                            colormap(app.UIAxes_Stack, gray)
                        elseif size(temp,3) == 3
                            fS = imagesc(app.UIAxes_Stack, squeeze(app.image_stack(1,:,:,:)));
                        end
                        
                        app.UIAxes_Stack.XLim = [0 fS.XData(2)];        % scale to axes
                        app.UIAxes_Stack.YLim = [0 fS.YData(2)];

                        if size(app.image_stack, 1) == 1                % determines limits of channel slider
                            app.ChannelSlider.Limits = [1 2];
                        else
                            app.ChannelSlider.Limits = [1 size(app.image_stack, 1)];
                        end
                    else
                        uialert(app.UIFigure, 'This 2P image does not have a corresponding stack.', 'Missing stack image', 'Icon', 'error');
                    end

                elseif ~isempty(app.suffix2P) && strcmp(tile_seg{2}, strcat(app.prefix2P, '.tif'))  % variable prefix, constant suffix
                    app.tilename = file;
                    app.image_annotate = imread(app.tilename);
                    f = imagesc(app.UIAxes_2P, app.image_annotate);
                    app.UIAxes_2P.Colormap = gray;
                    app.UIAxes_2P.XLim = [0 f.XData(2)];                % scale to axes
                    app.UIAxes_2P.YLim = [0 f.YData(2)];

                    app.stackname = strsplit(app.tilename, app.separatorStack);
                    app.stackname = strcat(app.stackname{1}, app.separatorStack, app.suffixStack);
                    if exist(app.stackname, 'file')                     % retrieves corresponding stack based on 2P's file prefix
                        info = imfinfo(app.stackname);
                        for i = 1:size(info, 1)                         % loads in all frames of stack
                            if i == 1
                                temp = im2uint8(imread(app.stackname, 1));
                                app.image_stack = uint8(zeros(size(info, 1), size(temp, 1), size(temp, 2), 3));
                                app.image_stack(i,:,:,:) = temp;
                            else
                                temp = im2uint8(imread(app.stackname, i));
                                app.image_stack(i,:,:,:) = temp;
                            end
                        end
                        fS = imagesc(app.UIAxes_Stack, squeeze(app.image_stack(1,:,:,:)));
                        app.UIAxes_Stack.XLim = [0 fS.XData(2)];        % scale to axes
                        app.UIAxes_Stack.YLim = [0 fS.YData(2)];

                        if size(app.image_stack, 1) == 1                % determines limits of channel slider
                            app.ChannelSlider.Limits = [1 2];
                        else
                            app.ChannelSlider.Limits = [1 size(app.image_stack, 1)];
                        end
                    else
                        uialert(app.UIFigure, 'This 2P image does not have a corresponding stack.', 'Missing stack image', 'Icon', 'error');
                    end
                else
                    uialert(app.UIFigure, 'Please select a 2P image.', 'Invalid file', 'Icon', 'error');
                end

            end

            cd(home)

        end

        % Menu selected function: ImportAnnotationsMenu
        function ImportAnnotationsMenuSelected(app, event)
            if isempty(app.tilename)
                uialert(app.UIFigure, 'Please select a 2P image.', 'Missing 2P image', 'Icon', 'error');
            else
                filename = strcat(erase(app.tilename, '.tif'), '.mat');      % determines annotations exist
                cd('annotated_ellipse')
                if ~isfile(filename)
                    uialert('Annotation file does not exist in this folder! Check directory and reload.', 'Mising file', 'Icon', 'error');
                else
                    load(filename); %#ok<LOAD>
                    cd ..\
                    cell = struct2cell(cell_output);
                    for i = 3:length(cell)
                        if mod(i, 3) == 0
                            for j = 1:length(cell{i})
                                drawellipse(app.UIAxes_2P, 'Center', cell{i}{j}.Center, 'RotationAngle', cell{i}{j}.RotationAngle, ...
                                    'SemiAxes', cell{i}{j}.SemiAxes, 'LineWidth', 1, 'Color', cell{i}{j}.Color, 'FaceAlpha', 0, ...
                                    'Label', num2str(j), 'LabelAlpha', 0.2, 'LabelTextColor', cell{i}{j}.Color);
                            end
                        elseif mod(i, 3) == 1
                            for j = 1:length(cell{i})
                                drawellipse(app.UIAxes_Stack, 'Center', cell{i}{j}.Center, 'RotationAngle', cell{i}{j}.RotationAngle, ...
                                    'SemiAxes', cell{i}{j}.SemiAxes, 'LineWidth', 1, 'Color', cell{i}{j}.Color, 'FaceAlpha', 0, ...
                                    'Label', num2str(j), 'LabelAlpha', 0.2, 'LabelTextColor', cell{i}{j}.Color);
                            end
                        end
                    end
                end
            end

        end

        % Menu selected function: SaveMenu
        function SaveMenuSelected(app, event)
            app.output.image_name = app.tilename;                       % saves image information in output
            app.output.stack_name = app.stackname;
            
            buttonnames_output = strrep(strrep(app.buttonnames, ' ', ''), '-', '');         % omits spaces and hyphens from field names
            roivalues = {app.roi1, app.roi2, app.roi3, app.roi4, app.roi5, app.roi6, app.roi7, app.roi8, app.roi9, app.roi10};
            roisvalues = {app.roi1s, app.roi2s, app.roi3s, app.roi4s, app.roi5s, app.roi6s, app.roi7s, app.roi8s, app.roi9s, app.roi10s};

            for i = 1:length(buttonnames_output)
                if ~isempty(roivalues{i})                               % saves non-empty fields
                    app.output = setfield(app.output, buttonnames_output{i}, roivalues{i});
                    app.output = setfield(app.output, strcat(buttonnames_output{i}, '_stack'), roisvalues{i});
                    app.output = setfield(app.output, strcat('N_', buttonnames_output{i}), numel(roivalues{i}));
                end
            end

            cell_output = app.output;

            tile_seg = strsplit(app.tilename, '.');                     % names and saves .mat output
            tile_id = tile_seg{1};
            if ispc
                movefile(['.\data\' app.tilename], '.\annotated_ellipse');
                save(['.\annotated_ellipse\', tile_id, '.mat'], 'cell_output', '-mat');
                uialert(app.UIFigure, 'Output saved!', 'Saved', 'Icon', 'success');
            else
                movefile(['./data/' app.tilename], './annotated_ellipse');
                save(['./annotated_ellipse/', tile_id, '.mat'], 'cell_output', '-mat');
                uialert(app.UIFigure, 'Output saved!', 'Saved', 'Icon', 'success');
            end

        end

        % Menu selected function: Export2PImageMenu
        function Export2PImageMenuSelected(app, event)
            if isempty(app.tilename)
                uialert(app.UIFigure, 'Please select a 2P image.', 'Error', 'Icon', 'error');
            else                                                        % exports 2P image
                cd('annotated_ellipse')
                exportgraphics(app.UIAxes_2P, strrep(app.tilename, '.tif', '_2P.tif'));
                cd ..\
                uialert(app.UIFigure, '2P image saved!', 'Saved', 'Icon', 'success');
            end
        end

        % Menu selected function: ExportStackImageMenu
        function ExportStackImageMenuSelected(app, event)
            if isempty(app.tilename)
                uialert(app.UIFigure, 'Please select a stack image.', 'Error', 'Icon', 'error');
            else                                                        % exports stack image
                cd('annotated_ellipse')
                if app.ChannelSlider.Limits(2) < 2
                    exportgraphics(app.UIAxes_Stack, strrep(app.tilename, '.tif', '_stack.tif'));
                else
                    temp = app.ChannelSlider.Value;
                    for i = 1:app.ChannelSlider.Limits(2)
                        app.ChannelSlider.Value = i;
                        ChannelSliderValueChanged(app, i);
                        f = getframe(app.UIAxes_Stack);
                        [im,map] = frame2im(f);                         % converts images to matrices to append into TIF
                        if isempty(map)
                            rgb = im;
                        else
                            rgb = ind2rgb(im,map);
                        end

                        if i == 1
                            imwrite(rgb, strrep(app.tilename, '.tif', '_stack.tif'));
                        else
                            imwrite(rgb, strrep(app.tilename, '.tif', '_stack.tif'), 'WriteMode', 'append');
                        end
                    end
                end

                app.ChannelSlider.Value = temp;
                ChannelSliderValueChanged(app, app.ChannelSlider.Value);
                cd ..\
                uialert(app.UIFigure, 'Stack image saved!', 'Saved', 'Icon', 'success');
            end
        end

        % Menu selected function: ReformatFileNamesMenu
        function ReformatFileNamesMenuSelected(app, event)
            app.ReformatFileNamesMenu.Enable = 'off';

            app.reformatapp = file_name_reformatter(app, app.prefix2P, app.separator2P, app.suffix2P, ...
                app.prefixStack, app.separatorStack, app.suffixStack);  % exports current values to file_name_reformatter.mlapp
            
        end

        % Menu selected function: RenameChannelsMenu
        function RenameChannelsMenuSelected(app, event)
            app.RenameChannelsMenu.Enable = 'off';

            app.renameapp = channel_name_editor(app, app.buttonnames);

        end

        % Value changed function: ChannelSlider
        function ChannelSliderValueChanged(app, event)
            if ~isempty(app.image_annotate) && size(app.image_stack, 1) > 1
                value = round(app.ChannelSlider.Value);                 % changes frames of stack
                app.ChannelSlider.Value = value;
                fS = imagesc(app.UIAxes_Stack, squeeze(app.image_stack(value,:,:,:)));
                app.UIAxes_Stack.XLim = [0 fS.XData(2)];                % scale to axes
                app.UIAxes_Stack.YLim = [0 fS.YData(2)];
                overlay_ROI(app);
            end
            
        end

        % Window scroll wheel function: UIFigure
        function UIFigureWindowScrollWheel(app, event)
            verticalScrollCount = event.VerticalScrollCount;
            value = app.ChannelSlider.Value;
            if ~isempty(app.image_stack)                                % changes current frame based on amount of scrolling up/down
                if (verticalScrollCount < 0 && value > app.ChannelSlider.Limits(1)) || (verticalScrollCount > 0 && value < app.ChannelSlider.Limits(2))
                    app.ChannelSlider.Value = value + verticalScrollCount;
                    ChannelSliderValueChanged(app, event)               % calls function to change frames of stack
                end
            end

        end

        % Value changed function: Channel1Button
        function Channel1ButtonValueChanged(app, event)
            value = app.Channel1Button.Value;                           % enables drawing mode
            if value == 1
                app.index1 = app.index1 + 1;
                app.hroi1{app.index1} = drawellipse(app.UIAxes_2P, 'LineWidth', 1, 'Color', 'r', 'FaceAlpha', 0, 'Label', num2str(app.index1), 'LabelAlpha', 0.2, 'LabelTextColor', 'r');
                app.l1{app.index1} = addlistener(app.hroi1{app.index1}, 'ROIMoved', @(src, evt)ROIallevents1(app, app.hroi1{app.index1}, evt)); % creates listeners for 2P ROI
                app.l2{app.index1} = addlistener(app.hroi1{app.index1}, 'DeletingROI', @(src, evt)ROIallevents1(app, app.hroi1{app.index1}, evt));
                app.l3{app.index1} = addlistener(app.hroi1{app.index1}, 'ROIClicked', @(src, evt)ROIallevents1(app, app.hroi1{app.index1}, evt));
            end

        end

        % Value changed function: Channel2Button
        function Channel2ButtonValueChanged(app, event)
            value = app.Channel2Button.Value;
            if value == 1
                app.index2 = app.index2 + 1;
                app.hroi2{app.index2} = drawellipse(app.UIAxes_2P, 'LineWidth', 1, 'Color', 'g', 'FaceAlpha', 0, 'Label', num2str(app.index2), 'LabelAlpha', 0.2, 'LabelTextColor', 'g');
                app.l1{app.index2} = addlistener(app.hroi2{app.index2}, 'ROIMoved', @(src, evt)ROIallevents2(app, app.hroi2{app.index2}, evt));
                app.l2{app.index2} = addlistener(app.hroi2{app.index2}, 'DeletingROI', @(src, evt)ROIallevents2(app, app.hroi2{app.index2}, evt));
                app.l3{app.index2} = addlistener(app.hroi2{app.index2}, 'ROIClicked', @(src, evt)ROIallevents2(app, app.hroi2{app.index2}, evt));
            end
            
        end

        % Value changed function: Channel3Button
        function Channel3ButtonValueChanged(app, event)
            value = app.Channel3Button.Value;
            if value == 1
                app.index3 = app.index3 + 1;
                app.hroi3{app.index3} = drawellipse(app.UIAxes_2P, 'LineWidth', 1, 'Color', 'b', 'FaceAlpha', 0, 'Label', num2str(app.index3), 'LabelAlpha', 0.2, 'LabelTextColor', 'b');
                app.l1{app.index3} = addlistener(app.hroi3{app.index3}, 'ROIMoved', @(src, evt)ROIallevents3(app, app.hroi3{app.index3}, evt));
                app.l2{app.index3} = addlistener(app.hroi3{app.index3}, 'DeletingROI', @(src, evt)ROIallevents3(app, app.hroi3{app.index3}, evt));
                app.l3{app.index3} = addlistener(app.hroi3{app.index3}, 'ROIClicked', @(src, evt)ROIallevents3(app, app.hroi3{app.index3}, evt));
            end
            
        end

        % Value changed function: Channel4Button
        function Channel4ButtonValueChanged(app, event)
            value = app.Channel4Button.Value;
            if value == 1
                app.index4 = app.index4 + 1;
                app.hroi4{app.index4} = drawellipse(app.UIAxes_2P, 'LineWidth', 1, 'Color', 'c', 'FaceAlpha', 0, 'Label', num2str(app.index4), 'LabelAlpha', 0.2, 'LabelTextColor', 'c');
                app.l1{app.index4} = addlistener(app.hroi4{app.index4}, 'ROIMoved', @(src, evt)ROIallevents4(app, app.hroi4{app.index4}, evt));
                app.l2{app.index4} = addlistener(app.hroi4{app.index4}, 'DeletingROI', @(src, evt)ROIallevents4(app, app.hroi4{app.index4}, evt));
                app.l3{app.index4} = addlistener(app.hroi4{app.index4}, 'ROIClicked', @(src, evt)ROIallevents4(app, app.hroi4{app.index4}, evt));
            end
            
        end

        % Value changed function: Channel5Button
        function Channel5ButtonValueChanged(app, event)
            value = app.Channel5Button.Value;
            if value == 1
                app.index5 = app.index5 + 1;
                app.hroi5{app.index5} = drawellipse(app.UIAxes_2P, 'LineWidth', 1, 'Color', 'm', 'FaceAlpha', 0, 'Label', num2str(app.index5), 'LabelAlpha', 0.2, 'LabelTextColor', 'm');
                app.l1{app.index5} = addlistener(app.hroi5{app.index5}, 'ROIMoved', @(src, evt)ROIallevents5(app, app.hroi5{app.index5}, evt));
                app.l2{app.index5} = addlistener(app.hroi5{app.index5}, 'DeletingROI', @(src, evt)ROIallevents5(app, app.hroi5{app.index5}, evt));
                app.l3{app.index5} = addlistener(app.hroi5{app.index5}, 'ROIClicked', @(src, evt)ROIallevents5(app, app.hroi5{app.index5}, evt));
            end
            
        end

        % Value changed function: Channel6Button
        function Channel6ButtonValueChanged(app, event)
            value = app.Channel6Button.Value;
            if value == 1
                app.index6 = app.index6 + 1;
                app.hroi6{app.index6} = drawellipse(app.UIAxes_2P, 'LineWidth', 1, 'Color', 'y', 'FaceAlpha', 0, 'Label', num2str(app.index6), 'LabelAlpha', 0.2, 'LabelTextColor', 'y');
                app.l1{app.index6} = addlistener(app.hroi6{app.index6}, 'ROIMoved', @(src, evt)ROIallevents6(app, app.hroi6{app.index6}, evt));
                app.l2{app.index6} = addlistener(app.hroi6{app.index6}, 'DeletingROI', @(src, evt)ROIallevents6(app, app.hroi6{app.index6}, evt));
                app.l3{app.index6} = addlistener(app.hroi6{app.index6}, 'ROIClicked', @(src, evt)ROIallevents6(app, app.hroi6{app.index6}, evt));
            end
            
        end

        % Value changed function: Channel7Button
        function Channel7ButtonValueChanged(app, event)
            value = app.Channel7Button.Value;
            if value == 1
                app.index7 = app.index7 + 1;
                app.hroi7{app.index7} = drawellipse(app.UIAxes_2P, 'LineWidth', 1, 'Color', 'k', 'FaceAlpha', 0, 'Label', num2str(app.index7), 'LabelAlpha', 0.2, 'LabelTextColor', 'k');
                app.l1{app.index7} = addlistener(app.hroi7{app.index7}, 'ROIMoved', @(src, evt)ROIallevents7(app, app.hroi7{app.index7}, evt));
                app.l2{app.index7} = addlistener(app.hroi7{app.index7}, 'DeletingROI', @(src, evt)ROIallevents7(app, app.hroi7{app.index7}, evt));
                app.l3{app.index7} = addlistener(app.hroi7{app.index7}, 'ROIClicked', @(src, evt)ROIallevents7(app, app.hroi7{app.index7}, evt));
            end
            
        end

        % Value changed function: Channel8Button
        function Channel8ButtonValueChanged(app, event)
            value = app.Channel8Button.Value;
            if value == 1
                app.index8 = app.index8 + 1;
                app.hroi8{app.index8} = drawellipse(app.UIAxes_2P, 'LineWidth', 1, 'Color', 'w', 'FaceAlpha', 0, 'Label', num2str(app.index8), 'LabelAlpha', 0.2, 'LabelTextColor', 'w');
                app.l1{app.index8} = addlistener(app.hroi8{app.index8}, 'ROIMoved', @(src, evt)ROIallevents8(app, app.hroi8{app.index8}, evt));
                app.l2{app.index8} = addlistener(app.hroi8{app.index8}, 'DeletingROI', @(src, evt)ROIallevents8(app, app.hroi8{app.index8}, evt));
                app.l3{app.index8} = addlistener(app.hroi8{app.index8}, 'ROIClicked', @(src, evt)ROIallevents8(app, app.hroi8{app.index8}, evt));
            end
            
        end

        % Value changed function: Channel9Button
        function Channel9ButtonValueChanged(app, event)
            value = app.Channel9Button.Value;
            if value == 1
                app.index9 = app.index9 + 1;
                app.hroi9{app.index9} = drawellipse(app.UIAxes_2P, 'LineWidth', 1, 'Color', [0.6350 0.0780 0.1840], 'FaceAlpha', 0, 'Label', num2str(app.index1), 'LabelAlpha', 0.2, 'LabelTextColor', [0.6350 0.0780 0.1840]);
                app.l1{app.index9} = addlistener(app.hroi9{app.index9}, 'ROIMoved', @(src, evt)ROIallevents9(app, app.hroi9{app.index9}, evt));
                app.l2{app.index9} = addlistener(app.hroi9{app.index9}, 'DeletingROI', @(src, evt)ROIallevents9(app, app.hroi9{app.index9}, evt));
                app.l3{app.index9} = addlistener(app.hroi9{app.index9}, 'ROIClicked', @(src, evt)ROIallevents9(app, app.hroi9{app.index9}, evt));
            end
            
        end

        % Value changed function: Channel10Button
        function Channel10ButtonValueChanged(app, event)
            value = app.Channel10Button.Value;
            if value == 1
                app.index10 = app.index10 + 1;
                app.hroi10{app.index10} = drawellipse(app.UIAxes_2P, 'LineWidth', 1, 'Color', [0 0.4470 0.7410], 'FaceAlpha', 0, 'Label', num2str(app.index1), 'LabelAlpha', 0.2, 'LabelTextColor', [0 0.4470 0.7410]);
                app.l1{app.index10} = addlistener(app.hroi10{app.index10}, 'ROIMoved', @(src, evt)ROIallevents10(app, app.hroi10{app.index10}, evt));
                app.l2{app.index10} = addlistener(app.hroi10{app.index10}, 'DeletingROI', @(src, evt)ROIallevents10(app, app.hroi10{app.index10}, evt));
                app.l3{app.index10} = addlistener(app.hroi10{app.index10}, 'ROIClicked', @(src, evt)ROIallevents10(app, app.hroi10{app.index10}, evt));
            end
            
        end

        % Button pushed function: AdjustContrastButton
        function AdjustContrastButtonPushed(app, event)
            imcontrast(app.UIAxes_2P)
        end

        % Button pushed function: AdjustContrastButton_2
        function AdjustContrastButton_2Pushed(app, event)
            imcontrast(app.UIAxes_Stack)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.WindowScrollWheelFcn = createCallbackFcn(app, @UIFigureWindowScrollWheel, true);
            app.FileMenu = uimenu(app.UIFigure);
            app.FileMenu.Text = 'File';
            app.OpenMenu = uimenu(app.FileMenu);
            app.OpenMenu.MenuSelectedFcn = createCallbackFcn(app, @OpenMenuSelected, true);
            app.OpenMenu.Text = 'Open';
            app.ImportAnnotationsMenu = uimenu(app.FileMenu);
            app.ImportAnnotationsMenu.MenuSelectedFcn = createCallbackFcn(app, @ImportAnnotationsMenuSelected, true);
            app.ImportAnnotationsMenu.Text = 'Import Annotations';
            app.SaveMenu = uimenu(app.FileMenu);
            app.SaveMenu.MenuSelectedFcn = createCallbackFcn(app, @SaveMenuSelected, true);
            app.SaveMenu.Text = 'Save';
            app.Export2PImageMenu = uimenu(app.FileMenu);
            app.Export2PImageMenu.MenuSelectedFcn = createCallbackFcn(app, @Export2PImageMenuSelected, true);
            app.Export2PImageMenu.Text = 'Export 2P Image';
            app.ExportStackImageMenu = uimenu(app.FileMenu);
            app.ExportStackImageMenu.MenuSelectedFcn = createCallbackFcn(app, @ExportStackImageMenuSelected, true);
            app.ExportStackImageMenu.Text = 'Export Stack Image';
            app.SettingsMenu = uimenu(app.UIFigure);
            app.SettingsMenu.Text = 'Settings';
            app.ReformatFileNamesMenu = uimenu(app.SettingsMenu);
            app.ReformatFileNamesMenu.MenuSelectedFcn = createCallbackFcn(app, @ReformatFileNamesMenuSelected, true);
            app.ReformatFileNamesMenu.Text = 'Reformat File Names';
            app.RenameChannelsMenu = uimenu(app.SettingsMenu);
            app.RenameChannelsMenu.MenuSelectedFcn = createCallbackFcn(app, @RenameChannelsMenuSelected, true);
            app.RenameChannelsMenu.Text = 'Rename Channels';
            app.UIAxes_Stack = uiaxes(app.UIFigure);
            app.UIAxes_Stack.XTick = [];
            app.UIAxes_Stack.YTick = [];
            app.UIAxes_Stack.ZTick = [];
            app.UIAxes_2P = uiaxes(app.UIFigure);
            app.UIAxes_2P.XTick = [];
            app.UIAxes_2P.YTick = [];
            app.UIAxes_2P.ZTick = [];
            app.AdjustContrastButton_2 = uibutton(app.UIFigure, 'push');
            app.AdjustContrastButton_2.ButtonPushedFcn = createCallbackFcn(app, @AdjustContrastButton_2Pushed, true);
            app.AdjustContrastButton_2.Text = 'Adjust Contrast';
            app.AdjustContrastButton = uibutton(app.UIFigure, 'push');
            app.AdjustContrastButton.ButtonPushedFcn = createCallbackFcn(app, @AdjustContrastButtonPushed, true);
            app.AdjustContrastButton.Text = 'Adjust Contrast';
            app.Channel10Button = uibutton(app.UIFigure, 'state');
            app.Channel10Button.ValueChangedFcn = createCallbackFcn(app, @Channel10ButtonValueChanged, true);
            app.Channel10Button.Text = 'Channel 10';
            app.Channel9Button = uibutton(app.UIFigure, 'state');
            app.Channel9Button.ValueChangedFcn = createCallbackFcn(app, @Channel9ButtonValueChanged, true);
            app.Channel9Button.Text = 'Channel 9';
            app.Channel8Button = uibutton(app.UIFigure, 'state');
            app.Channel8Button.ValueChangedFcn = createCallbackFcn(app, @Channel8ButtonValueChanged, true);
            app.Channel8Button.Text = 'Channel 8';
            app.Channel7Button = uibutton(app.UIFigure, 'state');
            app.Channel7Button.ValueChangedFcn = createCallbackFcn(app, @Channel7ButtonValueChanged, true);
            app.Channel7Button.Text = 'Channel 7';
            app.Channel6Button = uibutton(app.UIFigure, 'state');
            app.Channel6Button.ValueChangedFcn = createCallbackFcn(app, @Channel6ButtonValueChanged, true);
            app.Channel6Button.Text = 'Channel 6';
            app.Channel5Button = uibutton(app.UIFigure, 'state');
            app.Channel5Button.ValueChangedFcn = createCallbackFcn(app, @Channel5ButtonValueChanged, true);
            app.Channel5Button.Text = 'Channel 5';
            app.Channel4Button = uibutton(app.UIFigure, 'state');
            app.Channel4Button.ValueChangedFcn = createCallbackFcn(app, @Channel4ButtonValueChanged, true);
            app.Channel4Button.Text = 'Channel 4';
            app.Channel3Button = uibutton(app.UIFigure, 'state');
            app.Channel3Button.ValueChangedFcn = createCallbackFcn(app, @Channel3ButtonValueChanged, true);
            app.Channel3Button.Text = 'Channel 3';
            app.Channel2Button = uibutton(app.UIFigure, 'state');
            app.Channel2Button.ValueChangedFcn = createCallbackFcn(app, @Channel2ButtonValueChanged, true);
            app.Channel2Button.Text = 'Channel 2';
            app.Channel1Button = uibutton(app.UIFigure, 'state');
            app.Channel1Button.ValueChangedFcn = createCallbackFcn(app, @Channel1ButtonValueChanged, true);
            app.Channel1Button.Text = 'Channel 1';
            app.ChannelSliderLabel = uilabel(app.UIFigure);
            app.ChannelSliderLabel.Text = 'Channel';
            app.ChannelSlider = uislider(app.UIFigure);
            app.ChannelSlider.Limits = [1 10];
            app.ChannelSlider.MajorTicks = [1 2 3 4 5 6 7 8 9 10];
            app.ChannelSlider.ValueChangedFcn = createCallbackFcn(app, @ChannelSliderValueChanged, true);
            app.ChannelSlider.MinorTicks = [];
            app.ChannelSlider.Value = 1;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = cell_annotation

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end