classdef channel_name_editor < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        SaveButton              matlab.ui.control.Button
        ChannelNameEditorLabel  matlab.ui.control.Label
        DropDown                matlab.ui.control.DropDown
        EditField               matlab.ui.control.EditField
        SaveasDefaultButton     matlab.ui.control.Button
        CancelButton            matlab.ui.control.Button
    end

    
    properties (Access = private)
        CallingApp                                                      % call_annotation.mlapp
        buttonnames
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, caller, buttonnames)
            app.CallingApp = caller;                                    % stores primary app

            app.buttonnames = buttonnames;                              % imports current values from cell_annotation.mlapp
            app.DropDown.Items = app.buttonnames;

        end

        % Value changed function: EditField
        function EditFieldValueChanged(app, event)
            value = app.EditField.Value;                                % gets new name
            index = find(strcmp(app.DropDown.Value, app.DropDown.Items));

            old = app.buttonnames{index};
            app.buttonnames{index} = value;

            app.DropDown.Items = app.buttonnames;                       % initialises changes
            uialert(app.UIFigure, append(old, ' has been changed to ', value, '.'), 'Channel Renamed', 'Icon', 'success');
            app.EditField.Value = '';

        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            update_channel_names(app.CallingApp, app.buttonnames)   % exports new values to cell_annotation.mlapp
            uialert(app.CallingApp.UIFigure, 'Channel names saved!', 'Saved', 'Icon', 'success');
            delete(app)                                             % closes dialogue box

        end

        % Button pushed function: SaveasDefaultButton
        function SaveasDefaultButtonPushed(app, event)
            channel_names = app.buttonnames;
            save(['channel_names', '.mat'], 'channel_names', '-mat');   % saves current channel names to .mat file for future session
            uialert(app.UIFigure, 'Channel names saved!', 'Saved', 'Icon', 'success');

        end

        % Button pushed function: CancelButton
        function CancelButtonPushed(app, event)
            app.CallingApp.RenameChannelsMenu.Enable = 'on';                      % reenables button on primary app

            delete(app)

        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            app.CallingApp.RenameChannelsMenu.Enable = 'on';                      % reenables button on primary app
            
            delete(app)
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.CancelButton = uibutton(app.UIFigure, 'push');
            app.CancelButton.ButtonPushedFcn = createCallbackFcn(app, @CancelButtonPushed, true);
            app.CancelButton.Text = 'Cancel';
            app.SaveasDefaultButton = uibutton(app.UIFigure, 'push');
            app.SaveasDefaultButton.ButtonPushedFcn = createCallbackFcn(app, @SaveasDefaultButtonPushed, true);
            app.SaveasDefaultButton.Text = 'Save as Default';
            app.EditField = uieditfield(app.UIFigure, 'text');
            app.EditField.ValueChangedFcn = createCallbackFcn(app, @EditFieldValueChanged, true);
            app.DropDown = uidropdown(app.UIFigure);
            app.DropDown.Items = {'Channel 1', 'Channel 2', 'Channel 3', 'Channel 4', 'Channel 5', 'Channel 6', 'Channel 7', 'Channel 8', 'Channel 9', 'Channel 10'};
            app.DropDown.Value = 'Channel 1';
            app.ChannelNameEditorLabel = uilabel(app.UIFigure);
            app.ChannelNameEditorLabel.Text = 'Channel Name Editor';
            app.SaveButton = uibutton(app.UIFigure, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = channel_name_editor(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end