classdef file_name_reformatter < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        PImageInformationLabel     matlab.ui.control.Label
        Prefix2PEditField          matlab.ui.control.EditField
        Separator2PEditField       matlab.ui.control.EditField
        Suffix2PEditField          matlab.ui.control.EditField
        StackInformationLabel      matlab.ui.control.Label
        PrefixStackEditField       matlab.ui.control.EditField
        SeparatorStackEditField    matlab.ui.control.EditField
        SuffixStackEditField       matlab.ui.control.EditField
        SaveButton                 matlab.ui.control.Button
        CancelButton               matlab.ui.control.Button
        SuffixEditField_2Label     matlab.ui.control.Label
        SeparatorEditField_2Label  matlab.ui.control.Label
        PrefixEditField_2Label     matlab.ui.control.Label
        SuffixEditFieldLabel       matlab.ui.control.Label
        SeparatorEditFieldLabel    matlab.ui.control.Label
        PrefixEditFieldLabel       matlab.ui.control.Label
    end

    
    properties (Access = private)
        CallingApp                                                      % call_annotation.mlapp
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, caller, prefix1, separator1, suffix1, prefix2, separator2, suffix2)
            app.CallingApp = caller;                                    % stores primary app

            app.Prefix2PEditField.Value = prefix1;                      % imports current values from cell_annotation.mlapp
            app.Separator2PEditField.Value = separator1;
            app.Suffix2PEditField.Value = suffix1;
            app.PrefixStackEditField.Value = prefix2;
            app.SeparatorStackEditField.Value = separator2;
            app.SuffixStackEditField.Value = suffix2;

        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            if xor(isempty(app.Prefix2PEditField.Value) & isempty(app.PrefixStackEditField.Value), isempty(app.Suffix2PEditField.Value) & isempty(app.SuffixStackEditField.Value)) ...
                    && ~isempty(app.Separator2PEditField.Value) && ~isempty(app.SeparatorStackEditField.Value)
                                                                        % determines to match based on prefix or suffix
                reformat_file_names(app.CallingApp, app.Prefix2PEditField.Value, app.Separator2PEditField.Value, app.Suffix2PEditField.Value, ...
                    app.PrefixStackEditField.Value, app.SeparatorStackEditField.Value, app.SuffixStackEditField.Value)
                                                                        % exports new values to cell_annotation.mlapp
                uialert(app.CallingApp.UIFigure, 'File name format saved!', 'Saved', 'Icon', 'success');
                delete(app)                                             % closes dialogue box
            else
                uialert(app.UIFigure, 'Either both prefixes or suffixes must be empty. Both separators must exist.', 'Invalid file format', 'Icon', 'error');
            end

        end

        % Button pushed function: CancelButton
        function CancelButtonPushed(app, event)
            app.CallingApp.ReformatFileNamesMenu.Enable = 'on';         % reenables button on primary app

            delete(app)

        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            app.CallingApp.Children.Enable = 'on';                      % reenables button on primary app
            
            delete(app)
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.PrefixEditFieldLabel = uilabel(app.UIFigure);
            app.PrefixEditFieldLabel.Text = 'Prefix';
            app.SeparatorEditFieldLabel = uilabel(app.UIFigure);
            app.SeparatorEditFieldLabel.Text = 'Separator';
            app.SuffixEditFieldLabel = uilabel(app.UIFigure);
            app.SuffixEditFieldLabel.Text = 'Suffix';
            app.PrefixEditField_2Label = uilabel(app.UIFigure);
            app.PrefixEditField_2Label.Text = 'Prefix';
            app.SeparatorEditField_2Label = uilabel(app.UIFigure);
            app.SeparatorEditField_2Label.Text = 'Separator';
            app.SuffixEditField_2Label = uilabel(app.UIFigure);
            app.SuffixEditField_2Label.Text = 'Suffix';
            app.CancelButton = uibutton(app.UIFigure, 'push');
            app.CancelButton.ButtonPushedFcn = createCallbackFcn(app, @CancelButtonPushed, true);
            app.CancelButton.Text = 'Cancel';
            app.SaveButton = uibutton(app.UIFigure, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save';
            app.SuffixStackEditField = uieditfield(app.UIFigure, 'text');
            app.SeparatorStackEditField = uieditfield(app.UIFigure, 'text');
            app.PrefixStackEditField = uieditfield(app.UIFigure, 'text');
            app.StackInformationLabel = uilabel(app.UIFigure);
            app.StackInformationLabel.Text = 'Stack Information';
            app.Suffix2PEditField = uieditfield(app.UIFigure, 'text');
            app.Separator2PEditField = uieditfield(app.UIFigure, 'text');
            app.Prefix2PEditField = uieditfield(app.UIFigure, 'text');
            app.PImageInformationLabel = uilabel(app.UIFigure);
            app.PImageInformationLabel.Text = '2P Image Information';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = file_name_reformatter(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end