classdef test4 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        LoadqBRMdataButton              matlab.ui.control.Button
        LoadlineannotationsButton       matlab.ui.control.Button
        MaxintensitySpinner             matlab.ui.control.Spinner
        ContinueButton                  matlab.ui.control.Button
        PauseButton                     matlab.ui.control.Button
        MaxintensityLabel               matlab.ui.control.Label
        TimeLbl                         matlab.ui.control.Label
        FramesSlider                    matlab.ui.control.Slider
        ClearAllButton                  matlab.ui.control.Button
        TabGroup                        matlab.ui.container.TabGroup
        ZstackTab                       matlab.ui.container.Tab
        UIAxes                          matlab.ui.control.UIAxes
        FocusstackTab                   matlab.ui.container.Tab
        UIAxesDS                        matlab.ui.control.UIAxes
        FramesSliderLabel               matlab.ui.control.Label
        IsolatedVesiclesPanel           matlab.ui.container.Panel
        DrawingmodeVButton              matlab.ui.control.StateButton
        isolatedvesiclesEditField       matlab.ui.control.NumericEditField
        isolatedvesiclesEditFieldLabel  matlab.ui.control.Label
        MyelinAbnormalitiesPanel        matlab.ui.container.Panel
        DrawingmodeMButton              matlab.ui.control.StateButton
        myelinabnormalitiesEditField    matlab.ui.control.NumericEditField
        myelinabnormalitiesEditFieldLabel  matlab.ui.control.Label
        FileIOPanel                     matlab.ui.container.Panel
        FinishSaveButton                matlab.ui.control.Button
        AxonalAbnormalitiesPanel        matlab.ui.container.Panel
        DrawingmodeAButton              matlab.ui.control.StateButton
        axonalabnormalitiesEditField    matlab.ui.control.NumericEditField
        axonalabnormalitiesEditFieldLabel  matlab.ui.control.Label
        SettingsPanel                   matlab.ui.container.Panel
        HideAllRectanglesSwitch         matlab.ui.control.Switch
        HideAllRectanglesSwitchLabel    matlab.ui.control.Label
        HideAllLinesSwitchLabel         matlab.ui.control.Label
        HideAllLinesSwitch              matlab.ui.control.Switch
        InfoButton                      matlab.ui.control.Button
    end

    
    properties (Access = private)
        RandTimer               % Timer object
    end
    
    properties (Access = public)
        output = {};            % output of the app
        index1 = 0;             % ROI index for vesicles
        index2 = 0;             % ROI index for myelin pathologies
        index3 = 0;             % ROI index for axonal pathologies         
        roi_line_vesicle        % rectangle ROI of vesicles
        roi_line_myelin         % rectangle ROI of myelin pathologies        
        roi_line_axonal         % rectangle ROI of axonal pathologies
        vesicle_lines           % line ROI of vesicles
        myelin_lines            % line ROI of myelin pathologies
        axonal_lines            % line ROI of axonal pathologies
        image                   % the image data
        image_a                 % the image data with annotations
        image_na                % the image data with no annotations
        image_DS                % defocused stack of the image data
        image_DS_a              % defocused stack of the image data with annotations
        image_DS_na             % defocused stack of the image data with no annotations
        image_DS_original
        image_DS_original_a
        image_DS_original_na
        RGB_img                 % RGB image of the segmentation
        N_vesicles              % number of isolated vesicles
        N_myelin_def            % number of myelin pathologies
        N_balloons              % number of axonal abnormalities
        hLine_vesicle           % current vesicle ROI
        hLine_myelin            % current myelin ROI        
        hLine_axonal            % current axonal ROI
        l1                      % listener for moving the vesicle ROI
        l2                      % listener for deleting the vesicle ROI
        l3                      % listener for double-clicking the vesicle ROI
        l4                      % listener for moving the myelin ROI
        l5                      % listener for deleting the myelin ROI
        l6                      % listener for double-clicking the myelin ROI
        l7                      % listener for moving the axonal ROI
        l8                      % listener for deleting the axonal ROI
        l9                      % listener for double-clicking the axonal ROI
        fDS                     % the ROI drawing figure
        tilename                % name of the image tile
        time = 0;
        Nch = 3;
    end

    methods (Access = private)
        function RandTimerFcn(app,~,~)
            % Generate a random number
            app.time = app.time + 1;
            app.TimeLbl.Text = num2str(app.time);
        end   
    end

    methods (Access = public)
        
        function overlay_ROI(app)               % draw ROI on UIAxes
            cla(app.UIAxesDS);
            app.fDS = imagesc(app.UIAxesDS, mat2gray(app.image_DS));
            if ~isempty(app.vesicle_lines)
                for i=1:length(app.vesicle_lines)
                    line_position = app.vesicle_lines{i};
                    if ~isempty(line_position)
                        drawline(app.UIAxes, 'Color', 'r', 'Position', line_position, 'LineWidth', 0.5, 'InteractionsAllowed', 'none');
                        drawline(app.UIAxesDS, 'Color', 'r','Position', line_position, 'LineWidth', 0.5, 'InteractionsAllowed', 'none');
                    end
                end
            end
            if ~isempty(app.myelin_lines)
                for i=1:length(app.myelin_lines)
                    line_position = app.myelin_lines{i};
                    if ~isempty(line_position)
                        drawline(app.UIAxes, 'Color', 'g', 'Position', line_position, 'LineWidth', 0.5, 'InteractionsAllowed', 'none');
                        drawline(app.UIAxesDS, 'Color', 'g', 'Position', line_position, 'LineWidth', 0.5, 'InteractionsAllowed', 'none');
                    end
                end
            end
            if ~isempty(app.axonal_lines)
                for i=1:length(app.axonal_lines)
                    line_position = app.axonal_lines{i};
                    if ~isempty(line_position)
                        drawline(app.UIAxes, 'Color','b', 'Position', line_position, 'LineWidth', 0.5, 'InteractionsAllowed', 'none');
                        drawline(app.UIAxesDS, 'Color','b', 'Position', line_position, 'LineWidth', 0.5, 'InteractionsAllowed', 'none');
                    end
                end
            end
            HideAllLinesSwitchValueChanged(app, app.HideAllLinesSwitch.Value)
        end

        function roiallevents1(app,~,evt)       % vesicle events
            evname = evt.EventName;
            sl = app.index1;
            if strcmp(evname,'ROIMoved')

            elseif strcmp(evname,'DeletingROI')
                delete(app.l1{sl});
                delete(app.l2{sl});
                delete(app.l3{sl});
                app.DrawingmodeVButton.Value = 0;
            elseif strcmp(evname,'ROIClicked') && strcmp(evt.SelectionType,'double')
                if ~isempty(app.hLine_vesicle{sl})
                    app.N_vesicles = app.N_vesicles + 1;
                    app.isolatedvesiclesEditField.Value = app.N_vesicles;
                    app.roi_line_vesicle{sl} = app.hLine_vesicle{sl}.Position;
                    app.UIAxesDS.NextPlot = 'add';
                    app.hLine_vesicle{app.index1}.InteractionsAllowed = 'none';
                    drawrectangle(app.UIAxes, 'Position', app.hLine_vesicle{sl}.Position, 'LineWidth', 3, 'Color', 'r', 'FaceAlpha', 0, 'InteractionsAllowed', 'none');
                    drawrectangle(app.UIAxesDS, 'Position', app.hLine_vesicle{sl}.Position, 'LineWidth', 3, 'Color', 'r', 'FaceAlpha', 0, 'InteractionsAllowed', 'none');

                    for i = 1:size(app.image, 1)
                        x_min = round(app.hLine_vesicle{sl}.Position(1));
                        y_min = round(app.hLine_vesicle{sl}.Position(2));
                        x_max = round(app.hLine_vesicle{sl}.Position(1) + app.hLine_vesicle{sl}.Position(3));
                        y_max = round(app.hLine_vesicle{sl}.Position(2) + app.hLine_vesicle{sl}.Position(4));

                        app.image_a(i, y_min, x_min:x_max, 1) = 255;
                        app.image_a(i, y_max, x_min:x_max, 1) = 255;
                        app.image_a(i, y_min:y_max, x_min, 1) = 255;
                        app.image_a(i, y_min:y_max, x_max, 1) = 255;

                        app.image_DS_a(y_min, x_min:x_max, 1) = 2^32-1;
                        app.image_DS_a(y_max, x_min:x_max, 1) = 2^32-1;
                        app.image_DS_a(y_min:y_max, x_min, 1) = 2^32-1;
                        app.image_DS_a(y_min:y_max, x_max, 1) = 2^32-1;

                        app.image_DS_original_a(y_min, x_min:x_max, 1) = 2^32-1;
                        app.image_DS_original_a(y_max, x_min:x_max, 1) = 2^32-1;
                        app.image_DS_original_a(y_min:y_max, x_min, 1) = 2^32-1;
                        app.image_DS_original_a(y_min:y_max, x_max, 1) = 2^32-1;
                    end
                    HideAllRectanglesSwitchValueChanged(app, app.HideAllRectanglesSwitch.Value)
                end
                delete(app.l1{sl});
                delete(app.l2{sl});
                delete(app.l3{sl});
                app.DrawingmodeVButton.Value = 0;
            end
        end

        function roiallevents2(app,~,evt)    % myelin events
            evname = evt.EventName;
            sl = app.index2;
            if strcmp(evname,'ROIMoved')

            elseif strcmp(evname,'DeletingROI')
                delete(app.l4{sl});
                delete(app.l5{sl});
                delete(app.l6{sl});
                app.DrawingmodeMButton.Value = 0;
            elseif strcmp(evname,'ROIClicked') && strcmp(evt.SelectionType,'double')
                if ~isempty(app.hLine_myelin{sl})
                    app.N_myelin_def = app.N_myelin_def + 1;
                    app.myelinabnormalitiesEditField.Value = app.N_myelin_def;
                    app.roi_line_myelin{sl} = app.hLine_myelin{sl}.Position;
                    app.UIAxesDS.NextPlot = 'add';
                    app.hLine_myelin{app.index2}.InteractionsAllowed = 'none';
                    drawrectangle(app.UIAxes, 'Position', app.hLine_myelin{sl}.Position, 'LineWidth', 3, 'Color', 'g', 'FaceAlpha', 0, 'InteractionsAllowed', 'none');
                    drawrectangle(app.UIAxesDS, 'Position', app.hLine_myelin{sl}.Position, 'LineWidth', 3, 'Color', 'g', 'FaceAlpha', 0, 'InteractionsAllowed', 'none');

                    for i = 1:size(app.image, 1)
                        x_min = round(app.hLine_myelin{sl}.Position(1));
                        y_min = round(app.hLine_myelin{sl}.Position(2));
                        x_max = round(app.hLine_myelin{sl}.Position(1) + app.hLine_myelin{sl}.Position(3));
                        y_max = round(app.hLine_myelin{sl}.Position(2) + app.hLine_myelin{sl}.Position(4));

                        app.image_a(i, y_min, x_min:x_max, 2) = 255;
                        app.image_a(i, y_max, x_min:x_max, 2) = 255;
                        app.image_a(i, y_min:y_max, x_min, 2) = 255;
                        app.image_a(i, y_min:y_max, x_max, 2) = 255;

                        app.image_DS_a(y_min, x_min:x_max, 2) = 2^32-1;
                        app.image_DS_a(y_max, x_min:x_max, 2) = 2^32-1;
                        app.image_DS_a(y_min:y_max, x_min, 2) = 2^32-1;
                        app.image_DS_a(y_min:y_max, x_max, 2) = 2^32-1;

                        app.image_DS_original_a(y_min, x_min:x_max, 2) = 2^32-1;
                        app.image_DS_original_a(y_max, x_min:x_max, 2) = 2^32-1;
                        app.image_DS_original_a(y_min:y_max, x_min, 2) = 2^32-1;
                        app.image_DS_original_a(y_min:y_max, x_max, 2) = 2^32-1;
                    end
                    HideAllRectanglesSwitchValueChanged(app, app.HideAllRectanglesSwitch.Value)
                end
                delete(app.l4{sl});
                delete(app.l5{sl});
                delete(app.l6{sl});
                app.DrawingmodeMButton.Value = 0;
            end
        end

        function roiallevents3(app,~,evt)    % axonal events
            evname = evt.EventName;
            sl = app.index3;
            if strcmp(evname,'ROIMoved')

            elseif strcmp(evname,'DeletingROI')
                delete(app.l7{sl});
                delete(app.l8{sl});
                delete(app.l9{sl});
                app.DrawingmodeAButton.Value=0;
            elseif strcmp(evname,'ROIClicked') && strcmp(evt.SelectionType,'double')
                if ~isempty(app.hLine_axonal{sl})
                    app.N_balloons = app.N_balloons + 1;
                    app.axonalabnormalitiesEditField.Value = app.N_balloons;
                    app.roi_line_axonal{sl} = app.hLine_axonal{sl}.Position;
                    app.UIAxesDS.NextPlot = 'add';
                    app.hLine_axonal{app.index3}.InteractionsAllowed = 'none';
                    drawrectangle(app.UIAxes, 'Position', app.hLine_axonal{sl}.Position, 'LineWidth', 3, 'Color', 'b', 'FaceAlpha', 0, 'InteractionsAllowed', 'none');
                    drawrectangle(app.UIAxesDS, 'Position', app.hLine_axonal{sl}.Position, 'LineWidth', 3, 'Color', 'b', 'FaceAlpha', 0, 'InteractionsAllowed', 'none');
                
                    for i = 1:size(app.image, 1)
                        x_min = round(app.hLine_axonal{sl}.Position(1));
                        y_min = round(app.hLine_axonal{sl}.Position(2));
                        x_max = round(app.hLine_axonal{sl}.Position(1) + app.hLine_axonal{sl}.Position(3));
                        y_max = round(app.hLine_axonal{sl}.Position(2) + app.hLine_axonal{sl}.Position(4));

                        app.image_a(i, y_min, x_min:x_max, 3) = 255;
                        app.image_a(i, y_max, x_min:x_max, 3) = 255;
                        app.image_a(i, y_min:y_max, x_min, 3) = 255;
                        app.image_a(i, y_min:y_max, x_max, 3) = 255;

                        app.image_DS_a(y_min, x_min:x_max, 3) = 2^32-1;
                        app.image_DS_a(y_max, x_min:x_max, 3) = 2^32-1;
                        app.image_DS_a(y_min:y_max, x_min, 3) = 2^32-1;
                        app.image_DS_a(y_min:y_max, x_max, 3) = 2^32-1;

                        app.image_DS_original_a(y_min, x_min:x_max, 3) = 2^32-1;
                        app.image_DS_original_a(y_max, x_min:x_max, 3) = 2^32-1;
                        app.image_DS_original_a(y_min:y_max, x_min, 3) = 2^32-1;
                        app.image_DS_original_a(y_min:y_max, x_max, 3) = 2^32-1;
                    end
                    HideAllRectanglesSwitchValueChanged(app, app.HideAllRectanglesSwitch.Value)
                end
                delete(app.l7{sl});
                delete(app.l8{sl});
                delete(app.l9{sl});
                app.DrawingmodeAButton.Value = 0;
            end
        end

    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)

            % Create timer object
            app.RandTimer = timer(...
                'ExecutionMode', 'fixedRate', ...    % Run timer repeatedly
                'Period', 1, ...                     % Period is 1 second
                'BusyMode', 'queue',...              % Queue timer callbacks when busy
                'TimerFcn', @app.RandTimerFcn);      % Specify callback function
        end

        % Button pushed function: LoadqBRMdataButton
        function LoadqBRMdataButtonPushed(app, event)
            if strcmp(app.RandTimer.Running, 'off')
               start(app.RandTimer);
            else
                app.time = 0;
                stop(app.RandTimer);
                start(app.RandTimer);
            end

            clc;
            addpath(genpath(pwd));

            % re-initialize all the parameters
            app.index1 = 0;
            app.index3 = 0;
            app.index2 = 0;
            app.roi_line_vesicle = {};
            app.roi_line_myelin = {};
            app.roi_line_axonal = {};
            app.vesicle_lines = {};
            app.myelin_lines = {};
            app.axonal_lines = {};
            app.N_vesicles = 0;
            app.N_myelin_def = 0;
            app.N_balloons = 0;
            app.isolatedvesiclesEditField.Value = 0;
            app.myelinabnormalitiesEditField.Value = 0;
            app.axonalabnormalitiesEditField.Value = 0;

            % re-initialize all the UI axes
            cla(app.UIAxes);
            cla(app.UIAxesDS);
            title(app.UIAxes, []);
            xlabel(app.UIAxes, []);
            ylabel(app.UIAxes, []);
            app.UIAxes.XAxis.TickLabels = {};
            app.UIAxes.YAxis.TickLabels = {};
            title(app.UIAxesDS, []);
            xlabel(app.UIAxesDS, []);
            ylabel(app.UIAxesDS, []);
            app.UIAxesDS.XAxis.TickLabels = {};
            app.UIAxesDS.YAxis.TickLabels = {};

            % randomly read a tile from the directory
            filename = dir('.\annotated\*.tif');

            if ~exist([pwd 'annotated_rectangle'],'dir')
                mkdir annotated_rectangle;
            end

            % reads image
            if isempty(filename)
                msgbox('Image file is missing in this folder! Check directory and reload.', 'icon', 'error');
            else
                n_file = length(filename);
                file_id = randi(n_file,1);
                app.tilename = filename(file_id).name;
                info = imfinfo(['.\annotated\' app.tilename]);

                for u = 1:length(info)
                    if u == 1
                        temp = imread(['.\annotated\' app.tilename],1);
                        if size(temp,3)==1
                            app.Nch = 3;
                            app.image_a = double(zeros(length(info), size(temp, 1), size(temp,2), 3 ));
                            app.image_na = double(zeros(length(info), size(temp, 1), size(temp,2), 3 ));
                            temp = squeeze(temp);
                            temp = im2double(temp);
                            temp = repmat(temp, [1 1 3]);
                        else
                            app.Nch = 3;
                            app.image_a = double(zeros([length(info) size(temp)]));
                            app.image_na = double(zeros([length(info) size(temp)]));
                        end
                        app.image_a(u,:,:,:) = temp;
                        app.image_na(u,:,:,:) = temp;
                    else
                        image_rgb = imread(['.\annotated\' app.tilename],u);
                        image_rgb = squeeze(image_rgb);
                        image_rgb = im2double(image_rgb);
                        image_rgb = repmat(image_rgb, [1 1 3]);
                        app.image_a(u,:,:,:) = image_rgb;
                        app.image_na(u,:,:,:) = image_rgb;
                    end
                end

                if length(size(app.image_a))>2

                    app.MaxintensitySpinner.Value = 25;
                    if app.Nch == 1
                        f = imagesc(app.UIAxes, mat2gray(squeeze(app.image_a(1,:,:))));
                        app.UIAxes.CLim = [0 0.25];
                        app.UIAxes.Colormap = gray;
                    else
                        x =  app.MaxintensitySpinner.Value;
                        temp = squeeze(app.image_a(1,:,:,:))*(x/2);
                        f = imagesc(app.UIAxes, temp);
                    end

                    % Set limits of axes
                    app.UIAxes.XLim = [0 f.XData(2)];
                    app.UIAxes.YLim = [0 f.YData(2)];
                    app.FramesSlider.Limits = [1 size(app.image_a,1)];
                    app.FramesSlider.MinorTicks = [];
                    app.FramesSlider.Value = 1;
                    % set colormap limit

                    if app.Nch == 1
                        % generate focusing stack
                        h = waitbar(0,'Generating defocused stack ...');
                        app.image_DS_a = mat2gray(fstack(squeeze(num2cell(uint16(permute(app.image_a, [2 3 1])),[1 2])),...
                            'dilatesize', 9,'blendsize', 9));
                        waitbar(1,h,'Finished.');
                        close(h);
                        app.image_DS_na = app.image_DS_a;
                        app.image_DS_original_a = app.image_DS_a;
                        app.image_DS_original_na = app.image_DS_a;
                        app.fDS = imagesc(app.UIAxesDS, app.image_DS_a);
                        x =  app.MaxintensitySpinner.Value/100;
                        app.UIAxesDS.CLim = [0 x];
                        app.UIAxesDS.Colormap = gray;
                    else
                        % generate focusing stack
                        h = waitbar(0,'Generating defocused stack ...');
                        app.image_DS_a = fstack(squeeze(num2cell(uint32(permute(app.image_a*(2^32-1), [2 3 4 1])),[1 2 3])),...
                            'dilatesize', 9,'blendsize', 9);
                        waitbar(1,h,'Finished.');
                        close(h);
                        x = app.MaxintensitySpinner.Value;
                        app.image_DS_na = app.image_DS_a;
                        app.image_DS_original_a = app.image_DS_a;
                        app.image_DS_original_na = app.image_DS_a;
                        app.fDS = imagesc(app.UIAxesDS, app.image_DS_a*(x/2));
                    end
                    %app.UIAxesDS.Colormap = gray;
                    % Set limits of axes
                    app.UIAxesDS.XLim = [0 app.fDS.XData(2)];
                    app.UIAxesDS.YLim = [0 app.fDS.YData(2)];
                else
                    x =  app.MaxintensitySpinner.Value;
                    temp = app.image_DS_a*(x/2);
                    f = imagesc(app.UIAxes, temp);
                    %app.UIAxes.Colormap = gray;
                    % Set limits of axes
                    app.UIAxes.XLim = [0 f.XData(2)];
                    app.UIAxes.YLim = [0 f.YData(2)];
                    app.UIAxes.CLim = [0 0.25];
                    % read color limit
                    x =  app.MaxintensitySpinner.Value;
                    temp = app.image_DS_a*(x/2);
                    app.fDS = imagesc(app.UIAxesDS, temp);
                    %app.UIAxesDS.Colormap = gray;
                    % Set limits of axes
                    app.UIAxesDS.XLim = [0 app.fDS.XData(2)];
                    app.UIAxesDS.YLim = [0 app.fDS.YData(2)];
                end
            end

            HideAllRectanglesSwitchValueChanged(app, app.HideAllRectanglesSwitch.Value)
        end

        % Button pushed function: LoadlineannotationsButton
        function LoadlineannotationsButtonPushed(app, event)
            if isempty(app.tilename)
                msgbox('Load image file first!', 'icon', 'error');
            else
                filename = strcat('.\annotated\', erase(app.tilename, '.tif'),'.mat');
                if ~isfile(filename)
                    msgbox('Line annotation file is missing in this folder! Check directory and reload.', 'icon', 'error');
                else
                    load(filename); %#ok<LOAD>
                    app.vesicle_lines = UIoutput.vesicle;
                    app.myelin_lines = UIoutput.myelin;
                    app.axonal_lines = UIoutput.balloon;
                    if length(app.vesicle_lines) + length(app.myelin_lines) + length(app.axonal_lines) == 0
                        msgbox('There are no abnormalities.', 'icon', 'warn');
                    end
                end
            end

            for i = 1:length(app.vesicle_lines)
                line_position = app.vesicle_lines{i};
                drawline(app.UIAxes, 'Color', 'r', 'Position', line_position, 'LineWidth', 0.5, 'InteractionsAllowed', 'none');
                drawline(app.UIAxesDS, 'Color', 'r', 'Position', line_position, 'LineWidth', 0.5, 'InteractionsAllowed', 'none'); 
            end
            for i = 1:length(app.myelin_lines)
                line_position = app.myelin_lines{i};
                drawline(app.UIAxes, 'Color', 'g', 'Position', line_position, 'LineWidth', 0.5, 'InteractionsAllowed', 'none');
                drawline(app.UIAxesDS, 'Color', 'g', 'Position', line_position, 'LineWidth', 0.5, 'InteractionsAllowed', 'none'); 
            end
            for i = 1:length(app.axonal_lines)
                line_position = app.axonal_lines{i};
                drawline(app.UIAxes, 'Color', 'b', 'Position', line_position, 'LineWidth', 0.5, 'InteractionsAllowed', 'none');
                drawline(app.UIAxesDS, 'Color', 'b', 'Position', line_position, 'LineWidth', 0.5, 'InteractionsAllowed', 'none'); 
            end
        end

        % Value changed function: FramesSlider
        function FramesSliderValueChanged(app, event)
            if ~isempty(app.image)
                value = round(app.FramesSlider.Value);
                app.FramesSlider.Value = value;
                app.UIAxes.NextPlot = 'replacechildren';
                x =  app.MaxintensitySpinner.Value;
                if app.Nch == 1
                    f = imagesc(app.UIAxes, mat2gray(squeeze(app.image(value,:,:,:))));
                    app.UIAxes.Colormap = gray;
                    app.UIAxes.CLim = [0 x];
                else
                    temp = squeeze(app.image(value,:,:,:))*(x/2);
                    f = imagesc(app.UIAxes, temp);
                end
                % Set limits of axes
                app.UIAxes.XLim = [0 f.XData(2)];
                app.UIAxes.YLim = [0 f.YData(2)];
                overlay_ROI(app);
            else
                msgbox('Load image file first!', 'icon', 'error');
            end
        end

        % Value changing function: FramesSlider
        function FramesSliderValueChanging(app, event)
            if ~isempty(app.image)
                changingValue = event.Value;
                cvalue = round(changingValue);
                app.UIAxes.NextPlot = 'replacechildren';
                x =  app.MaxintensitySpinner.Value;
                if app.Nch == 1
                    temp = mat2gray(squeeze(app.image(cvalue,:,:)));
                    f = imagesc(app.UIAxes, temp);
                    app.UIAxes.CLim = [0 x];
                else
                    temp = squeeze(app.image(cvalue,:,:,:))*(x/2);
                    f = imagesc(app.UIAxes, temp);
                end
                %app.UIAxes.Colormap = gray;
                % Set limits of axes
                app.UIAxes.XLim = [0 f.XData(2)];
                app.UIAxes.YLim = [0 f.YData(2)];
                overlay_ROI(app);
            end
        end

        % Value changed function: MaxintensitySpinner
        function MaxintensitySpinnerValueChanged(app, event)

            svalue = round(app.FramesSlider.Value);
            app.UIAxes.NextPlot = 'replacechildren';
            x =  app.MaxintensitySpinner.Value;
            if app.Nch == 1
                temp = mat2gray(squeeze(app.image(svalue,:,:,:)));
                f = imagesc(app.UIAxes, temp);
                app.UIAxes.CLim = [0 x];
            else
                temp = squeeze(app.image(svalue,:,:,:))*(x/2);
                f = imagesc(app.UIAxes, temp);
            end
            % Set limits of axes
            app.UIAxes.XLim = [0 f.XData(2)];
            app.UIAxes.YLim = [0 f.YData(2)];

            x =  app.MaxintensitySpinner.Value;
            if app.Nch == 1
                temp = mat2gray(app.image_DS);
                app.fDS = imagesc(app.UIAxes, temp);
                app.UIAxesDS.CLim = [0 x];
            else
                app.image_DS = app.image_DS_original*(x/2);
                %app.fDS = imagesc(app.UIAxesDS, app.image_DS);
                %app.fDS = imagesc(app.UIAxesDS, mat2gray(app.image_DS));
            end
            app.UIAxes.XLim = [0 app.fDS.XData(2)];
            app.UIAxes.YLim = [0 app.fDS.YData(2)];
            overlay_ROI(app);
        end

        % Window scroll wheel function: UIFigure
        function UIFigureWindowScrollWheel(app, event)
            % verticalScrollAmount = event.VerticalScrollAmount;
            verticalScrollCount = event.VerticalScrollCount;
            if ~isempty(app.image)
                if verticalScrollCount > 0
                    value = app.FramesSlider.Value;
                    if value < app.FramesSlider.Limits(2)
                        app.UIAxes.NextPlot = 'replacechildren';
                        x =  app.MaxintensitySpinner.Value;
                        if app.Nch == 1
                            temp = mat2gray(squeeze(app.image(value+1,:,:)));
                            f = imagesc(app.UIAxes, temp);
                            app.UIAxes.CLim = [0 x];
                        else
                            temp = squeeze(app.image(value+1,:,:,:))*(x/2);
                            f = imagesc(app.UIAxes, temp);
                        end
                        app.FramesSlider.Value = value +1;
                        %app.UIAxes.Colormap = gray;
                        % Set limits of axes
                        app.UIAxes.XLim = [0 f.XData(2)];
                        app.UIAxes.YLim = [0 f.YData(2)];
                        overlay_ROI(app);
                    end
                elseif verticalScrollCount < 0
                    value = app.FramesSlider.Value;
                    if value > app.FramesSlider.Limits(1)
                        app.UIAxes.NextPlot = 'replacechildren';
                        x =  app.MaxintensitySpinner.Value;
                        if app.Nch == 1
                            temp = mat2gray(squeeze(app.image(value,:,:,:)));
                            f = imagesc(app.UIAxes, temp);
                            app.UIAxes.CLim = [0 x];             
                        else
                            temp = squeeze(app.image(value,:,:,:))*(x/2);
                            f = imagesc(app.UIAxes, temp);
                        end
                        app.FramesSlider.Value = value -1;
                        %app.UIAxes.Colormap = gray;
                        % Set limits of axes
                        app.UIAxes.XLim = [0 f.XData(2)];
                        app.UIAxes.YLim = [0 f.YData(2)];
                        overlay_ROI(app);
                    end
                end
            end
        end

        % Key press function: UIFigure
        function UIFigureKeyPress(app, event)
            key = event.Key;
            switch (key)
                case 'shift'
                    if strcmp(app.HideAllLinesSwitch.Value, 'Off')
                        app.HideAllLinesSwitch.Value = 'On';
                    else
                        app.HideAllLinesSwitch.Value = 'Off';
                    end
                    HideAllLinesSwitchValueChanged(app, app.HideAllLinesSwitch.Value)
                case 'control'
                    if strcmp(app.HideAllRectanglesSwitch.Value, 'Off')
                        app.HideAllRectanglesSwitch.Value = 'On';
                    else
                        app.HideAllRectanglesSwitch.Value = 'Off';
                    end
                    HideAllRectanglesSwitchValueChanged(app, app.HideAllRectanglesSwitch.Value)
            end
        end

        % Button pushed function: FinishSaveButton
        function FinishSaveButtonPushed(app, event)
            stop(app.RandTimer);

            % save the counting numbers and the abnormalities features
            app.output.image_name = app.tilename;
            app.output.vesicle = app.roi_line_vesicle;
            app.output.myelin = app.roi_line_myelin;            
            app.output.axonal = app.roi_line_axonal;
            app.output.N_vesicles = app.N_vesicles;
            app.output.N_myelin_def = app.N_myelin_def;
            app.output.N_balloons = app.N_balloons;
            app.output.N_abnorm = app.N_vesicles + app.N_myelin_def + app.N_balloons;
            app.output.time = app.time;
            app.time = 0;
            gTruth = app.output;
            if isempty(app.roi_line_axonal) && isempty(app.roi_line_vesicle) && (app.N_myelin_def == 0)
                answer = questdlg('No abnormalities is labeled! Do you want to save?','No abnormalities labeled','Yes','No','No');
                if strcmp(answer,'Yes')
                        tile_seg = strsplit(app.tilename,'.');
                        tile_id = tile_seg{1};
                        movefile(['.\annotated\' app.tilename], '.\annotated_rectangle');
                        save(['.\annotated_rectangle\',tile_id,'.mat'],'gTruth','-mat');
                        msgbox('Output saved!')
                end
            else
                tile_seg = strsplit(app.tilename,'.');
                tile_id = tile_seg{1};
                movefile(['.\annotated\' app.tilename], '.\annotated_rectangle');
                save(['.\annotated_rectangle\',tile_id,'.mat'],'gTruth','-mat');
                msgbox('Output saved!')
            end
        end

        % Button pushed function: PauseButton
        function PauseButtonPushed(app, event)
            stop(app.RandTimer);
        end

        % Button pushed function: ContinueButton
        function ContinueButtonPushed(app, event)
            start(app.RandTimer);
        end

        % Value changed function: DrawingmodeVButton
        function DrawingmodeVButtonValueChanged(app, event)
            value = app.DrawingmodeVButton.Value;
            if value == 1 && strcmp(app.TabGroup.SelectedTab.Title, 'Focus stack')
                app.index1 = app.index1 + 1;
                app.hLine_vesicle{app.index1} = drawrectangle(app.UIAxesDS, 'LineWidth', 1, 'Color', 'r', 'FaceAlpha', 0);
                hLine2 = drawrectangle(app.UIAxes, 'LineWidth', 1, 'Color', 'r', 'Position', app.hLine_vesicle{app.index1}.Position, 'FaceAlpha', 0, 'InteractionsAllowed', 'none');
                linkprop([app.hLine_vesicle{app.index1} hLine2], 'Position');
                % add listeners for adjusting ROI & updating ROI line profile
                app.l1{app.index1} = addlistener(app.hLine_vesicle{app.index1}, 'ROIMoved', @(src,evt)roiallevents1(app,app.hLine_vesicle{app.index1},evt));
                app.l2{app.index1} = addlistener(app.hLine_vesicle{app.index1}, 'DeletingROI', @(src,evt)roiallevents1(app,app.hLine_vesicle{app.index1},evt));
                app.l3{app.index1} = addlistener(app.hLine_vesicle{app.index1}, 'ROIClicked', @(src,evt)roiallevents1(app,app.hLine_vesicle{app.index1},evt));
            else
                app.index1 = app.index1 + 1;
                app.hLine_vesicle{app.index1} = drawrectangle(app.UIAxes, 'LineWidth', 1, 'Color', 'r', 'FaceAlpha', 0);
                hLine2 = drawrectangle(app.UIAxesDS, 'LineWidth', 1, 'Color', 'r', 'Position', app.hLine_vesicle{app.index1}.Position, 'FaceAlpha', 0, 'InteractionsAllowed', 'none');
                linkprop([app.hLine_vesicle{app.index1} hLine2], 'Position');
                % add listeners for adjusting ROI & updating ROI line profile
                app.l1{app.index1} = addlistener(app.hLine_vesicle{app.index1}, 'ROIMoved', @(src,evt)roiallevents1(app,app.hLine_vesicle{app.index1},evt));
                app.l2{app.index1} = addlistener(app.hLine_vesicle{app.index1}, 'DeletingROI', @(src,evt)roiallevents1(app,app.hLine_vesicle{app.index1},evt));
                app.l3{app.index1} = addlistener(app.hLine_vesicle{app.index1}, 'ROIClicked', @(src,evt)roiallevents1(app,app.hLine_vesicle{app.index1},evt));
            end
        end

        % Value changed function: DrawingmodeMButton
        function DrawingmodeMButtonValueChanged(app, event)
            value = app.DrawingmodeMButton.Value;
            if value == 1 && strcmp(app.TabGroup.SelectedTab.Title, 'Focus stack')
                app.index2 = app.index2 + 1;
                app.hLine_myelin{app.index2} = drawrectangle(app.UIAxesDS, 'LineWidth', 1, 'Color', 'g', 'FaceAlpha', 0);
                hLine2 = drawrectangle(app.UIAxes, 'LineWidth', 1, 'Color', 'g', 'Position', app.hLine_myelin{app.index2}.Position, 'FaceAlpha', 0, 'InteractionsAllowed', 'none');
                linkprop([app.hLine_myelin{app.index2} hLine2], 'Position');
                % add listeners for adjusting ROI & updating ROI line profile
                app.l4{app.index2} = addlistener(app.hLine_myelin{app.index2}, 'ROIMoved', @(src,evt)roiallevents2(app,app.hLine_myelin{app.index2},evt));
                app.l5{app.index2} = addlistener(app.hLine_myelin{app.index2}, 'DeletingROI', @(src,evt)roiallevents2(app,app.hLine_myelin{app.index2},evt));
                app.l6{app.index2} = addlistener(app.hLine_myelin{app.index2}, 'ROIClicked', @(src,evt)roiallevents2(app,app.hLine_myelin{app.index2},evt));
            else
                app.index2 = app.index2 + 1;
                app.hLine_myelin{app.index2} = drawrectangle(app.UIAxes, 'LineWidth', 1, 'Color', 'g', 'FaceAlpha', 0);
                hLine2 = drawrectangle(app.UIAxesDS, 'LineWidth', 1, 'Color', 'g', 'Position', app.hLine_myelin{app.index2}.Position, 'FaceAlpha', 0, 'InteractionsAllowed', 'none');
                linkprop([app.hLine_myelin{app.index2} hLine2], 'Position');
                % add listeners for adjusting ROI & updating ROI line profile
                app.l4{app.index2} = addlistener(app.hLine_myelin{app.index2}, 'ROIMoved', @(src,evt)roiallevents2(app,app.hLine_myelin{app.index2},evt));
                app.l5{app.index2} = addlistener(app.hLine_myelin{app.index2}, 'DeletingROI', @(src,evt)roiallevents2(app,app.hLine_myelin{app.index2},evt));
                app.l6{app.index2} = addlistener(app.hLine_myelin{app.index2}, 'ROIClicked', @(src,evt)roiallevents2(app,app.hLine_myelin{app.index2},evt));
            end
        end

        % Value changed function: DrawingmodeAButton
        function DrawingmodeAButtonValueChanged(app, event)
            value = app.DrawingmodeAButton.Value;
            if value == 1 && strcmp(app.TabGroup.SelectedTab.Title, 'Focus stack')
                app.index3 = app.index3 + 1;
                app.hLine_axonal{app.index3} = drawrectangle(app.UIAxesDS, 'LineWidth', 1,'Color', 'b', 'FaceAlpha', 0);
                hLine2 = drawrectangle(app.UIAxes, 'LineWidth', 1, 'Color', 'b', 'Position', app.hLine_axonal{app.index3}.Position, 'FaceAlpha', 0, 'InteractionsAllowed', 'none');
                linkprop([app.hLine_axonal{app.index3} hLine2], 'Position');
                % add listeners for adjusting ROI & updating ROI line profile
                app.l7{app.index3} = addlistener(app.hLine_axonal{app.index3}, 'ROIMoved', @(src,evt)roiallevents3(app,app.hLine_axonal{app.index3},evt));
                app.l8{app.index3} = addlistener(app.hLine_axonal{app.index3}, 'DeletingROI', @(src,evt)roiallevents3(app,app.hLine_axonal{app.index3},evt));
                app.l9{app.index3} = addlistener(app.hLine_axonal{app.index3}, 'ROIClicked', @(src,evt)roiallevents3(app,app.hLine_axonal{app.index3},evt));
            else
                app.index3 = app.index3 + 1;
                app.hLine_axonal{app.index3} = drawrectangle(app.UIAxes, 'LineWidth', 1,'Color', 'b', 'FaceAlpha', 0);
                hLine2 = drawrectangle(app.UIAxesDS, 'LineWidth', 1, 'Color', 'b', 'Position', app.hLine_axonal{app.index3}.Position, 'FaceAlpha', 0, 'InteractionsAllowed', 'none');
                linkprop([app.hLine_axonal{app.index3} hLine2], 'Position');
                % add listeners for adjusting ROI & updating ROI line profile
                app.l7{app.index3} = addlistener(app.hLine_axonal{app.index3}, 'ROIMoved', @(src,evt)roiallevents3(app,app.hLine_axonal{app.index3},evt));
                app.l8{app.index3} = addlistener(app.hLine_axonal{app.index3}, 'DeletingROI', @(src,evt)roiallevents3(app,app.hLine_axonal{app.index3},evt));
                app.l9{app.index3} = addlistener(app.hLine_axonal{app.index3}, 'ROIClicked', @(src,evt)roiallevents3(app,app.hLine_axonal{app.index3},evt));
            end
        end

        % Value changed function: HideAllLinesSwitch
        function HideAllLinesSwitchValueChanged(app, event)
            value = app.HideAllLinesSwitch.Value;
            if strcmp(value, 'On')
                set(findobj(app.UIAxes, 'Type', 'images.roi.line'), 'visible', 'off')
                set(findobj(app.UIAxesDS, 'Type', 'images.roi.line'), 'visible', 'off')
            else
                set(findobj(app.UIAxes, 'Type', 'images.roi.line'), 'visible', 'on')
                set(findobj(app.UIAxesDS, 'Type', 'images.roi.line'), 'visible', 'on')
            end     
        end

        % Value changed function: HideAllRectanglesSwitch
        function HideAllRectanglesSwitchValueChanged(app, event)
            value = app.HideAllRectanglesSwitch.Value;
            if strcmp(value, 'On')
                set(findobj(app.UIAxes, 'Type', 'images.roi.rectangle'), 'visible', 'off')
                set(findobj(app.UIAxesDS, 'Type', 'images.roi.rectangle'), 'visible', 'off')

                app.image = app.image_na;
                app.image_DS = app.image_DS_na;
                app.image_DS_original = app.image_DS_original_na;
            else
                set(findobj(app.UIAxes, 'Type', 'images.roi.rectangle'), 'visible', 'off')
                set(findobj(app.UIAxesDS, 'Type', 'images.roi.rectangle'), 'visible', 'off')
                
                app.image = app.image_a;
                app.image_DS = app.image_DS_a;
                app.image_DS_original = app.image_DS_original_a;
            end
            
            svalue = round(app.FramesSlider.Value);
            x =  app.MaxintensitySpinner.Value;
            temp = squeeze(app.image(svalue,:,:,:))*(x/2);
            f = imagesc(app.UIAxes, temp);

            app.image_DS = app.image_DS_original*(x/2);

            overlay_ROI(app);
        end

        % Button pushed function: InfoButton
        function InfoButtonPushed(app, event)
            msgbox('Press Shift to toggle hiding lines and Ctrl to toggle hiding rectangles.', 'icon', 'help')
        end

        % Button pushed function: ClearAllButton
        function ClearAllButtonPushed(app, event)
            childlist = get(app.UIAxesDS, 'children');
            childlist(end) = [];
            if ~isempty(childlist)
                delete(childlist);
            end

            % re-initialize all the parameters
            app.roi_line_vesicle = {};
            app.roi_line_myelin = {};
            app.roi_line_axonal = {};
            app.N_vesicles = 0;
            app.N_myelin_def = 0;
            app.N_balloons = 0;
            app.isolatedvesiclesEditField.Value = 0;
            app.myelinabnormalitiesEditField.Value = 0;
            app.axonalabnormalitiesEditField.Value = 0;
            app.index1 = 0;
            app.index2 = 0;
            app.index3 = 0;

            overlay_ROI(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.WindowScrollWheelFcn = createCallbackFcn(app, @UIFigureWindowScrollWheel, true);
            app.UIFigure.KeyPressFcn = createCallbackFcn(app, @UIFigureKeyPress, true);
            app.SettingsPanel = uipanel(app.UIFigure);
            app.SettingsPanel.Title = 'Settings';
            app.InfoButton = uibutton(app.SettingsPanel, 'push');
            app.InfoButton.ButtonPushedFcn = createCallbackFcn(app, @InfoButtonPushed, true);
            app.InfoButton.Text = 'Info';
            app.HideAllLinesSwitch = uiswitch(app.SettingsPanel, 'slider');
            app.HideAllLinesSwitch.ValueChangedFcn = createCallbackFcn(app, @HideAllLinesSwitchValueChanged, true);
            app.HideAllLinesSwitchLabel = uilabel(app.SettingsPanel);
            app.HideAllLinesSwitchLabel.Text = 'Hide All Lines';
            app.HideAllRectanglesSwitchLabel = uilabel(app.SettingsPanel);
            app.HideAllRectanglesSwitchLabel.Text = 'Hide All Rectangles';
            app.HideAllRectanglesSwitch = uiswitch(app.SettingsPanel, 'slider');
            app.HideAllRectanglesSwitch.ValueChangedFcn = createCallbackFcn(app, @HideAllRectanglesSwitchValueChanged, true);
            app.AxonalAbnormalitiesPanel = uipanel(app.UIFigure);
            app.AxonalAbnormalitiesPanel.Title = 'Axonal Abnormalities';
            app.axonalabnormalitiesEditFieldLabel = uilabel(app.AxonalAbnormalitiesPanel);
            app.axonalabnormalitiesEditFieldLabel.Text = '# axonal abnormalities';
            app.axonalabnormalitiesEditField = uieditfield(app.AxonalAbnormalitiesPanel, 'numeric');
            app.axonalabnormalitiesEditField.Editable = 'off';
            app.DrawingmodeAButton = uibutton(app.AxonalAbnormalitiesPanel, 'state');
            app.DrawingmodeAButton.ValueChangedFcn = createCallbackFcn(app, @DrawingmodeAButtonValueChanged, true);
            app.DrawingmodeAButton.Text = 'Drawing mode';
            app.FileIOPanel = uipanel(app.UIFigure);
            app.FileIOPanel.Title = 'File I/O';
            app.FinishSaveButton = uibutton(app.FileIOPanel, 'push');
            app.FinishSaveButton.ButtonPushedFcn = createCallbackFcn(app, @FinishSaveButtonPushed, true);
            app.FinishSaveButton.Text = 'Finish & Save';
            app.MyelinAbnormalitiesPanel = uipanel(app.UIFigure);
            app.MyelinAbnormalitiesPanel.Title = 'Myelin Abnormalities';
            app.myelinabnormalitiesEditFieldLabel = uilabel(app.MyelinAbnormalitiesPanel);
            app.myelinabnormalitiesEditFieldLabel.Text = '# myelin abnormalities';
            app.myelinabnormalitiesEditField = uieditfield(app.MyelinAbnormalitiesPanel, 'numeric');
            app.myelinabnormalitiesEditField.Editable = 'off';
            app.DrawingmodeMButton = uibutton(app.MyelinAbnormalitiesPanel, 'state');
            app.DrawingmodeMButton.ValueChangedFcn = createCallbackFcn(app, @DrawingmodeMButtonValueChanged, true);
            app.DrawingmodeMButton.Text = 'Drawing mode';
            app.IsolatedVesiclesPanel = uipanel(app.UIFigure);
            app.IsolatedVesiclesPanel.Title = 'Isolated Vesicles';
            app.isolatedvesiclesEditFieldLabel = uilabel(app.IsolatedVesiclesPanel);
            app.isolatedvesiclesEditFieldLabel.Text = '#  isolated vesicles';
            app.isolatedvesiclesEditField = uieditfield(app.IsolatedVesiclesPanel, 'numeric');
            app.isolatedvesiclesEditField.Editable = 'off';
            app.DrawingmodeVButton = uibutton(app.IsolatedVesiclesPanel, 'state');
            app.DrawingmodeVButton.ValueChangedFcn = createCallbackFcn(app, @DrawingmodeVButtonValueChanged, true);
            app.DrawingmodeVButton.Text = 'Drawing mode';
            app.FramesSliderLabel = uilabel(app.UIFigure);
            app.FramesSliderLabel.Text = 'Frames';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.ZstackTab = uitab(app.TabGroup);
            app.ZstackTab.Title = 'Z stack';
            app.UIAxes = uiaxes(app.ZstackTab);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.Visible = 'off';
            app.FocusstackTab = uitab(app.TabGroup);
            app.FocusstackTab.Title = 'Focus stack';
            app.UIAxesDS = uiaxes(app.FocusstackTab);
            title(app.UIAxesDS, 'Title')
            xlabel(app.UIAxesDS, 'X')
            ylabel(app.UIAxesDS, 'Y')
            zlabel(app.UIAxesDS, 'Z')
            app.UIAxesDS.Visible = 'off';
            app.ClearAllButton = uibutton(app.UIFigure, 'push');
            app.ClearAllButton.ButtonPushedFcn = createCallbackFcn(app, @ClearAllButtonPushed, true);
            app.ClearAllButton.Text = 'Clear All';
            app.FramesSlider = uislider(app.UIFigure);
            app.FramesSlider.Limits = [0 15];
            app.FramesSlider.ValueChangedFcn = createCallbackFcn(app, @FramesSliderValueChanged, true);
            app.FramesSlider.ValueChangingFcn = createCallbackFcn(app, @FramesSliderValueChanging, true);
            app.FramesSlider.MinorTicks = [];
            app.TimeLbl = uilabel(app.UIFigure);
            app.MaxintensityLabel = uilabel(app.UIFigure);
            app.MaxintensityLabel.Text = 'Max intensity (%)';
            app.PauseButton = uibutton(app.UIFigure, 'push');
            app.PauseButton.ButtonPushedFcn = createCallbackFcn(app, @PauseButtonPushed, true);
            app.PauseButton.Text = 'Pause';
            app.ContinueButton = uibutton(app.UIFigure, 'push');
            app.ContinueButton.ButtonPushedFcn = createCallbackFcn(app, @ContinueButtonPushed, true);
            app.ContinueButton.Text = 'Continue';
            app.MaxintensitySpinner = uispinner(app.UIFigure);
            app.MaxintensitySpinner.ValueDisplayFormat = '%.0f';
            app.MaxintensitySpinner.ValueChangedFcn = createCallbackFcn(app, @MaxintensitySpinnerValueChanged, true);
            app.LoadlineannotationsButton = uibutton(app.UIFigure, 'push');
            app.LoadlineannotationsButton.ButtonPushedFcn = createCallbackFcn(app, @LoadlineannotationsButtonPushed, true);
            app.LoadlineannotationsButton.Text = 'Load line annotations';
            app.LoadqBRMdataButton = uibutton(app.UIFigure, 'push');
            app.LoadqBRMdataButton.ButtonPushedFcn = createCallbackFcn(app, @LoadqBRMdataButtonPushed, true);
            app.LoadqBRMdataButton.Text = 'Load qBRM data';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = test4

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end